classdef AllTrackAnalysisWindow < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        AllTrackAnalysisUIFigure        matlab.ui.Figure
        GridLayout                      matlab.ui.container.GridLayout
        LeftPanel                       matlab.ui.container.Panel
        GridLayout2                     matlab.ui.container.GridLayout
        PropertiesPanel                 matlab.ui.container.Panel
        TabGroup                        matlab.ui.container.TabGroup
        CustomTab                       matlab.ui.container.Tab
        ButtonGroup_4                   matlab.ui.container.ButtonGroup
        ExportallcalculatedvaluesButton  matlab.ui.control.Button
        VolumeDeterminationPanel        matlab.ui.container.Panel
        GridLayout6                     matlab.ui.container.GridLayout
        AlphaRadiusEditField            matlab.ui.control.NumericEditField
        AlphaRadiusEditFieldLabel       matlab.ui.control.Label
        UseCheckBox                     matlab.ui.control.CheckBox
        Switch_4                        matlab.ui.control.Switch
        DiffusionConstantSelectionButtonGroup  matlab.ui.container.ButtonGroup
        MSDfitButton                    matlab.ui.control.RadioButton
        ExternalButton                  matlab.ui.control.RadioButton
        ConfinementvsmeanjumpdistanceButton  matlab.ui.control.RadioButton
        VolumeAreaoccupiedbyTracksButton  matlab.ui.control.RadioButton
        StatisticsTab                   matlab.ui.container.Tab
        ButtonGroup_3                   matlab.ui.container.ButtonGroup
        CalculateDbyCumulativeJumpDistancesPanel  matlab.ui.container.Panel
        GridLayout7                     matlab.ui.container.GridLayout
        DinitalestimatesEditField       matlab.ui.control.EditField
        DinitalestimatesEditFieldLabel  matlab.ui.control.Label
        DiffusiontypesEditField         matlab.ui.control.EditField
        DiffusiontypesEditFieldLabel    matlab.ui.control.Label
        TrackingRadiusEditField         matlab.ui.control.EditField
        TrackingRadiusEditFieldLabel    matlab.ui.control.Label
        CalculateDbyCumulativeJumpDistancesButton  matlab.ui.control.RadioButton
        FitPopulationsbyMeanJumpDistanceButton  matlab.ui.control.RadioButton
        CalculateMSDDbyJumpDistancesButton  matlab.ui.control.RadioButton
        AverageTrackLengthButton        matlab.ui.control.RadioButton
        CumulativeTrackLengthButton     matlab.ui.control.RadioButton
        HistogramsTab                   matlab.ui.container.Tab
        ButtonGroup_2                   matlab.ui.container.ButtonGroup
        DiffusionTypeSwitchingFrequenciesButton  matlab.ui.control.RadioButton
        NumberofJumpsPerTrackButton     matlab.ui.control.RadioButton
        ConfinementRatioButton          matlab.ui.control.RadioButton
        ClassificationfromSwiftButton   matlab.ui.control.RadioButton
        MSDfromInternMSDFitButton       matlab.ui.control.RadioButton
        JumpAnglesButton                matlab.ui.control.RadioButton
        CumulativeMeanJumpDistancesButton  matlab.ui.control.RadioButton
        MeanJumpDistancesButton         matlab.ui.control.RadioButton
        DiffusionParametersfromSwiftButton  matlab.ui.control.RadioButton
        CumulativeJumpDistancesButton   matlab.ui.control.RadioButton
        JumpDistancesButton             matlab.ui.control.RadioButton
        GraphStatisticsPanel            matlab.ui.container.Panel
        UITable                         matlab.ui.control.Table
        ExportDataPanel                 matlab.ui.container.Panel
        GridLayout3                     matlab.ui.container.GridLayout
        FilenameEditField               matlab.ui.control.EditField
        FilenameEditFieldLabel          matlab.ui.control.Label
        SaveTableButton                 matlab.ui.control.Button
        SavetoSVGButton                 matlab.ui.control.Button
        SavetofigButton                 matlab.ui.control.Button
        SavetocsvButton                 matlab.ui.control.Button
        FoldernotSetLabel               matlab.ui.control.Label
        SetSaveFolderButton             matlab.ui.control.Button
        DataFilterPanel                 matlab.ui.container.Panel
        GridLayout4                     matlab.ui.container.GridLayout
        ActiveCheckBox                  matlab.ui.control.CheckBox
        DataFilterButton                matlab.ui.control.Button
        HistogramSettingsPanel          matlab.ui.container.Panel
        GridLayout5                     matlab.ui.container.GridLayout
        SizeofBinsEditField             matlab.ui.control.NumericEditField
        SizeofBinsEditFieldLabel        matlab.ui.control.Label
        NumberofBinsEditField           matlab.ui.control.NumericEditField
        NumberofBinsEditFieldLabel      matlab.ui.control.Label
        RightBorderEditField            matlab.ui.control.NumericEditField
        RightBorderEditFieldLabel       matlab.ui.control.Label
        LeftBorderEditField             matlab.ui.control.NumericEditField
        LeftBorderEditFieldLabel        matlab.ui.control.Label
        MovieSelectionPanel             matlab.ui.container.Panel
        ListBox                         matlab.ui.control.ListBox
        RightPanel                      matlab.ui.container.Panel
        GridLayout8                     matlab.ui.container.GridLayout
        Panel                           matlab.ui.container.Panel
        UIAxes                          matlab.ui.control.UIAxes
        AdditionalOptionsPanel          matlab.ui.container.Panel
        GridLayout9                     matlab.ui.container.GridLayout
        CalculateButton                 matlab.ui.control.Button
        FitPanel                        matlab.ui.container.Panel
        PerformFitCheckBox              matlab.ui.control.CheckBox
        ConfinementRatioButtonGroup     matlab.ui.container.ButtonGroup
        Switch_3                        matlab.ui.control.Switch
        NetLengthButton                 matlab.ui.control.RadioButton
        LengthButton                    matlab.ui.control.RadioButton
        RatioLengthNetLengthButton      matlab.ui.control.RadioButton
        DiffParametersButtonGroup       matlab.ui.container.ButtonGroup
        DButton                         matlab.ui.control.RadioButton
        MSDButton                       matlab.ui.control.RadioButton
        UnitsPanel                      matlab.ui.container.Panel
        Switch                          matlab.ui.control.Switch
        MSDPanel                        matlab.ui.container.Panel
        ButtonGroup                     matlab.ui.container.ButtonGroup
        LogRButton                      matlab.ui.control.RadioButton
        LinRButton                      matlab.ui.control.RadioButton
        DButton_2                       matlab.ui.control.RadioButton
        MSDButton_2                     matlab.ui.control.RadioButton
        AlphaButton                     matlab.ui.control.RadioButton
        Switch_2                        matlab.ui.control.Switch
        ComputeButton                   matlab.ui.control.Button
        PointstoFitEditField            matlab.ui.control.NumericEditField
        PointstoFitEditFieldLabel       matlab.ui.control.Label
        JumpAnglesButtonGroup           matlab.ui.container.ButtonGroup
        XYZButton_2                     matlab.ui.control.RadioButton
        XYButton_2                      matlab.ui.control.RadioButton
        JumpDistancesButtonGroup        matlab.ui.container.ButtonGroup
        XYZButton                       matlab.ui.control.RadioButton
        XYButton                        matlab.ui.control.RadioButton
        ZButton                         matlab.ui.control.RadioButton
        YButton                         matlab.ui.control.RadioButton
        XButton                         matlab.ui.control.RadioButton
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end

    properties (Access = public)
        ImportSettingsStruct %Settings from the Import Settings Window
    end
    
    properties (Access = private)
        VisualisationWindow %Connection to the Calling Window
        DataFilterWindow % Window to set advanced filters
        
        TrackData % Data converted to Unit/Unit
        ConcatTrackData %For all data analysis
        PixelFrameTrackData % Data converted to Pixel/Frame
        ConcatPixelFrameTrackData %for all data analysis
        
        SelectedUnitData %Data used for plotting
        SelectedPixelData %Data used for plotting
        
        FilteredIDs %ids left after filtering
        GroupedFilteredIDs %ids when using all tracks, adjusted to the single file
        IsUnitFlag %remembers if pixel/frame or unit/unit
        
        UnitCalculatedDataStruct %contains all the calculated valus from the data
        PixelCalculatedDataStruct %contains the values of pixelated data
        
        PolAxes %polar axes for the angles
        
        Min %value for the axes as left border
        Max %value for the axes as right border
        Unitsused %text to add for units
        DiffTypes %contains the different classified diffusion types
        
        DataFilterStruct % contains the filter set in the filterWindow
        
        SaveLocation %Folder to save to
        Is2dData %check if 2d or not
        OldFigSize %saves the old figure size for resizing
    end
    
    methods
        function grabFilter(app)
            %gets the filter nodes from the DataFilterWindow
            app.DataFilterStruct = app.DataFilterWindow.DataFilterStruct;
            %get the data to filter on
            if app.Switch.Value == "Pixel/Frame"
                SaveStructure = app.PixelCalculatedDataStruct;
            else
                SaveStructure = app.UnitCalculatedDataStruct;
            end
            %get the filtered ids
            finalIDs = filterTrackIDsByDataFilterStruc(app.DataFilterStruct, SaveStructure);
            if isempty(finalIDs)
                f = msgbox("No tracks found under these conditions");
            else
                app.FilteredIDs = finalIDs;
            end
            %send them to main app
            if app.ListBox.Value == "All Tracks"
                try
                    finalIDs = groupRestoreTrackIDsForVisualisation(app.TrackData, app.ConcatTrackData, app.FilteredIDs);
                    app.GroupedFilteredIDs = finalIDs;
                catch
                    f = msgbox("Could not propagate the filter settings to the track overview. No tracks left to plot?");
                end
            end
            app.VisualisationWindow.setFilteredIds(finalIDs, app.ListBox.Value);
            %show the plots accordingly
            managePlots(app);
        end
    end
    
    methods (Static)
        function struc = setSaveStruct()
            %sets the properties for the calculated values so they dont
            %need to be calcualted again and again
            s = struct();
            s.JumpDist.X = {};
            s.JumpDist.Y = {};
            s.JumpDist.Z = {};
            s.JumpDist.XY = {};
            s.JumpDist.XYZ = {};
            s.CumJumpDist.X = {};
            s.CumJumpDist.Y = {};
            s.CumJumpDist.Z = {};
            s.CumJumpDist.XY = {};
            s.CumJumpDist.XYZ = {};
            s.MeanJumpDist.X = {};
            s.MeanJumpDist.Y = {};
            s.MeanJumpDist.Z = {};
            s.MeanJumpDist.XY = {};
            s.MeanJumpDist.XYZ = {};
            s.CumMeanJumpDist.X = {};
            s.CumMeanJumpDist.Y = {};
            s.CumMeanJumpDist.Z = {};
            s.CumMeanJumpDist.XY = {};
            s.CumMeanJumpDist.XYZ = {};
            s.JumpAngles.XY = {};
            s.JumpAngles.XYZ = {};
            s.SwiftParams.MSD = {};
            s.SwiftParams.MSDerr = {};
            s.SwiftParams.D = {};
            s.SwiftParams.Derr = {};
            s.SwiftParams.type = {};
            s.SwiftParams.switchFreq = {};
            s.InternMSD.TrackIDs = {};
            s.InternMSD.XY.MSDclass = [];
            s.InternMSD.XY.Alpha = {};
            s.InternMSD.XY.a = {};
            s.InternMSD.XY.d = {};
            s.InternMSD.XY.logR = {};
            s.InternMSD.XY.linR = {};
            s.InternMSD.XYZ.MSDclass = [];
            s.InternMSD.XYZ.Alpha = {};
            s.InternMSD.XYZ.a = {};
            s.InternMSD.XYZ.d = {};
            s.InternMSD.XYZ.logR = {};
            s.InternMSD.XYZ.linR = {};
            s.JumpDistMSD.X = {};
            s.JumpDistMSD.Y = {};
            s.JumpDistMSD.Z = {};
            s.JumpDistMSD.XY = {};
            s.JumpDistMSD.XYZ = {};
            s.TrackLength.Steps = {};
            s.TrackLength.NetLength.XY = {};
            s.TrackLength.NetLength.XYZ = {};
            s.TrackLength.ConfRatio.XY = {};
            s.TrackLength.ConfRatio.XYZ = {};
            s.TrackLength.AbsLength.XY = {};
            s.TrackLength.AbsLength.XYZ = {};
            s.TrackLength.CumLen = {};
            s.TrackLength.MeanLen.XY = {};
            s.TrackLength.MeanLen.XYZ = {};
            struc = s;
        end
    end
    
    methods (Access = private)
        %GUI methods
        function updateMovieList(app)
            %adds all the files from the visualisation window. if more then
            %one, also add an all tracks option
            names = string([]);
            for i = 1:size(app.TrackData,1)
                names(i) = string(app.TrackData{i,2});
            end
            if size(app.TrackData,1) > 1
                names(end+1) = "All Tracks";
            end
            app.ListBox.Items = names;
        end
        
        function renameUnitUnitFieldOfDataSwitch(app)
            items = app.Switch.Items;
            lengthunit = app.VisualisationWindow.ImportSettingsStruct.customUnits.LengthUnit;
            timeunit = app.VisualisationWindow.ImportSettingsStruct.customUnits.TimeUnit;
            combinedUnit = join([lengthunit "/" timeunit], "");
            combinedUnit = convertStringsToChars(combinedUnit);
            items{1,2} = combinedUnit;
            app.Switch.Items = items;
        end
        
        function manageExportField(app)
            %enables export if something is plotted.
            try
                if size(app.UIAxes.Children) > 0
                    child = app.ExportDataPanel.Children;
                    app.ExportDataPanel.Enable = "on";
                    set(child, "Enable", "on");
                    return
                else
                    child = app.ExportDataPanel.Children;
                    app.ExportDataPanel.Enable = "off";
                    set(child, "Enable", "off");
                end
            catch
            end
            try
            if isa(app.Panel.Children, 'matlab.graphics.layout.TiledChartLayout')
                child = app.ExportDataPanel.Children;
                app.ExportDataPanel.Enable = "on";
                set(child, "Enable", "on");
                return
            else
                child = app.ExportDataPanel.Children;
                app.ExportDataPanel.Enable = "off";
                set(child, "Enable", "off");
            end
            catch
            end
            try
            if ~isempty(app.PolAxes)
                child = app.ExportDataPanel.Children;
                app.ExportDataPanel.Enable = "on";
                set(child, "Enable", "on");
                return
            else
                child = app.ExportDataPanel.Children;
                app.ExportDataPanel.Enable = "off";
                set(child, "Enable", "off");
                return
            end
            catch
            end
        end
        
        function [lb, rb, hc, hz] = getHistPlotProps(app)
            %gets the current settings in the gui for the histogram
            lb = app.LeftBorderEditField.Value;
            rb = app.RightBorderEditField.Value;
            hc = app.NumberofBinsEditField.Value;
            hz = app.SizeofBinsEditField.Value;
        end
        
        function manageExtraPanel(app)
            %enables/disables the extra panels depending on the active
            %category
            set(app.GridLayout9.Children, "Visible", "off");
            set(app.CalculateButton, "Visible", "off");
            set(app.GridLayout7.Children, "Enable", "off");
            set(app.CalculateDbyCumulativeJumpDistancesPanel, "Enable", "off");
            set(app.DiffusionConstantSelectionButtonGroup, "Enable", "off");
            set(app.VolumeDeterminationPanel, "Enable", "off");
            set(app.GridLayout6.Children, "Enable", "off");
            app.UnitsPanel.Visible = 1;
            app.FitPanel.Visible = 1;
            tab = app.TabGroup.SelectedTab.Title;
            if tab == "Histograms"
                if app.JumpDistancesButton.Value || app.CumulativeJumpDistancesButton.Value || app.MeanJumpDistancesButton.Value || app.CumulativeMeanJumpDistancesButton.Value
                    app.JumpDistancesButtonGroup.Visible = 1;
                    child = app.JumpDistancesButtonGroup.Children;
                    set(child, "Enable", "on");
                    if app.Is2dData
                        app.ZButton.Enable = "off";
                        app.XYZButton.Enable = "off";
                    end
                elseif app.JumpAnglesButton.Value
                    app.JumpAnglesButtonGroup.Visible = 1;
                    if app.Is2dData
                        app.XYZButton_2.Enable = "off";
                    end
                elseif app.MSDfromInternMSDFitButton.Value
                    app.MSDPanel.Visible = 1;
                    if app.Is2dData
                        app.Switch_2.Value = "2D";
                        app.Switch_2.Enable = "off";
                    else
                        app.Switch_2.Enable = "on";
                    end
                elseif app.DiffusionParametersfromSwiftButton.Value
                    app.DiffParametersButtonGroup.Visible = 1;
                elseif app.ConfinementRatioButton.Value
                    app.ConfinementRatioButtonGroup.Visible = 1;
                    if app.Is2dData
                        app.Switch_3.Value = "2D";
                        app.Switch_3.Enable = "off";
                    else
                        app.Switch_3.Enable = "on";
                    end
                elseif app.CumulativeTrackLengthButton.Value || app.AverageTrackLengthButton.Value 
                    app.XYButton.Visible = 1;
                    app.XYZButton.Visible = 1;
                elseif app.CalculateDbyCumulativeJumpDistancesButton.Value
                    app.CalculateDbyCumulativeJumpDistancesPanel.Enable = "on";
                    child = app.CalculateDbyCumulativeJumpDistancesPanel.Children;
                    set(child, "Enable", "on");
                end
            elseif tab == "Statistics"
                if app.CalculateDbyCumulativeJumpDistancesButton.Value
                    app.CalculateDbyCumulativeJumpDistancesPanel.Enable = "on";
                    child = app.GridLayout7.Children;
                    set(child,"Enable","on");
                elseif app.FitPopulationsbyMeanJumpDistanceButton.Value
                    app.CalculateDbyCumulativeJumpDistancesPanel.Enable = "on";
                    app.JumpDistancesButtonGroup.Visible = 1;
                    app.XButton.Enable = "off";
                    app.YButton.Enable = "off";
                    app.ZButton.Enable = "off";
                    app.DiffusiontypesEditField.Enable = "on";
                end
            elseif tab == "Custom"   
                if app.ConfinementvsmeanjumpdistanceButton.Value
                    app.JumpDistancesButtonGroup.Enable = "on";
                    app.JumpDistancesButtonGroup.Visible = "on";
                    child = app.JumpDistancesButtonGroup.Children;
                    set(child,"Enable","on");
                    set(child,"Visible","on");
                    app.DiffusionConstantSelectionButtonGroup.Enable = "on";
                    app.XButton.Enable = "off";
                    app.YButton.Enable = "off";
                    app.ZButton.Enable = "off";
                    if app.Is2dData
                        app.XYZButton.Enable = "off";
                    end
                elseif app.VolumeAreaoccupiedbyTracksButton.Value
                    app.JumpDistancesButtonGroup.Enable = "on";
                    app.JumpDistancesButtonGroup.Visible = "on";
                    child = app.JumpDistancesButtonGroup.Children;
                    set(child,"Enable","on");
                    set(child,"Visible","on");
                    app.XButton.Enable = "off";
                    app.YButton.Enable = "off";
                    app.ZButton.Enable = "off";
                    set(app.VolumeDeterminationPanel, "Enable", "on");
                    set(app.GridLayout6.Children, "Enable", "on");
                    if app.Is2dData
                        app.XYZButton.Enable = "off";
                    else
                        app.XYZButton.Enable = "on";
                    end
                end
            end
        end
        
        function concatData(app)
           %decides the data that will be used for the analysis. if all
           %tracks is used, will concatenate all datafiles to one big file
            if size(app.TrackData,1) > 1
                app.ConcatTrackData = catTrackDataRename(app.TrackData);
                app.ConcatPixelFrameTrackData = catTrackDataRename(app.PixelFrameTrackData);
            end
        end
        
        function adjustDataAndScale(app)
            %when offering more data import option, I have to adjust the
            %data so that is uniform and that it can be used as an overlay
            %for the microscopy image. As I cant scale the image in a sens
            %of making pixel smaller, i will have to work with the
            %track/spot data.
            %4 options: pixel/frame, pixel/unit, unit/frame and unit/unit
            %mic data is pixel/frame, so we have to make the tracks fit
            if app.IsUnitFlag == 0 %has not been run
                type = split(convertCharsToStrings(app.VisualisationWindow.ImportSettingsStruct.customUnits.DataType),"/");
                comparison = strcmp(type, ["Pixel"; "Frame"]);
                if comparison %pixel/frame
                    %if pixel/frame, nothing much has to be done
                    app.PixelFrameTrackData = app.TrackData;
                    for i = 1:size(app.Trackdata,1)
                        tmpdat = app.TrackData{i,1};
                        tmpdat(:,2) = round(tmpdat(:,2)*app.VisualisationWindow.ImportSettingsStruct.customUnits.Timestep,0);
                        tmpdat(:,3:5) = tmpdat(:,3:5)*app.VisualisationWindow.ImportSettingsStruct.customUnits.Pixelsize;
                        app.TrackData{i,1} = tmpdat;
                    end
                elseif comparison(1) & (~comparison(2))%Pixel/Unit
                    %if pixel/unit, must convert unit frame
                    app.PixelFrameTrackData = app.TrackData;
                    for i = 1:size(app.TrackData,1)
                        tmpdat = app.TrackData{i,1};
                        time = tmpdat(:,2);
                        time = round(time/app.VisualisationWindow.ImportSettingsStruct.customUnits.Timestep,0);
                        tmpdat1 = tmpdat;
                        tmpdat1(:,2) = time;
                        app.PixelFrameTrackData{i,1} = tmpdat1;
                        tmpdat(:,3:5) = tmpdat(:,3:5)*app.VisualisationWindow.ImportSettingsStruct.customUnits.Pixelsize;
                        app.TrackData{i,1} = tmpdat;
                    end
                    elseif (~comparison(1)) & comparison(2)%Unit/Frame
                    %if unit/frame, must convert unit to pixel with the scale
                    app.PixelFrameTrackData = app.TrackData;
                    for i = 1:size(app.TrackData,1)
                        tmpdat = app.TrackData{i,1};
                        xyzdata = tmpdat(:,3:5);
                        xyzdata = xyzdata/app.VisualisationWindow.ImportSettingsStruct.customUnits.Pixelsize;
                        time = tmpdat(:,2);
                        time = round(time*app.VisualisationWindow.ImportSettingsStruct.customUnits.Timestep,0);
                        tmpdat1 = tmpdat;
                        tmpdat1(:,3:5) = xyzdata;
                        app.PixelFrameTrackData{i,1} = tmpdat1;
                        tmpdat(:,2) = time;
                        app.TrackData{i,1} = tmpdat;
                    end   
                else
                    %if unit/unit, must convert both
                    app.PixelFrameTrackData = app.TrackData;
                    for i = 1:size(app.TrackData,1)
                        tmpdat = app.TrackData{i,1};
                        time = tmpdat(:,2);
                        time = round(time/app.VisualisationWindow.ImportSettingsStruct.customUnits.Timestep,0);
                        xyzdata = tmpdat(:,3:5);
                        xyzdata = xyzdata/app.VisualisationWindow.ImportSettingsStruct.customUnits.Pixelsize;
                        tmpdat(:,2) = time;
                        tmpdat(:,3:5) = xyzdata;
                        app.PixelFrameTrackData{i,1} = tmpdat;
                    end
                end
            else %has been run
                %nothing
            end
        end

        %Plot Methods
        
        function setMinMaxValue(app, min, max)
            %sets the edit field values depending on the data that will be
            %plotted
            app.LeftBorderEditField.Value = min;
            app.RightBorderEditField.Value = max;
        end
        
        function adjustNumberSizeBinsbyNumber(app)
            hismin = app.LeftBorderEditField.Value;
            hismax = app.RightBorderEditField.Value;
            nmbr = app.NumberofBinsEditField.Value;
            
            size = round((hismax-hismin)/nmbr,2);
            app.SizeofBinsEditField.Value = size;
        end
        
        function adjustNumberSizeBinsbySize(app)
            hismin = app.LeftBorderEditField.Value;
            hismax = app.RightBorderEditField.Value;
            size = app.SizeofBinsEditField.Vlaue;
            
            nmbr = ceil((hismax-hismin)/size);
            app.NumberofBinsEditField.Value = nmbr;
        end
        
        function updatePlot(app)
            %redraws the plot
            [lb, rb, ~] = getHistPlotProps(app);
            xlim(app.UIAxes,[lb rb]);
        end
        
        function selectDataToPlot(app)
            %determines which data is needed for the plotting step
            %and returns it for the plotting function
            %single file, all data, unit or pixel
            unitswitchval = app.Switch.Value;
            dataswitchval = app.ListBox.Value;
            
            %search for the data
            %determine if single or multi selection
            datavalsz = size(dataswitchval);
            if datavalsz(1,2)>1
                %multiple files
                %check that all tracks are not part of that, if yes, remove
                for i = 1:datavalsz(1,2)
                    if dataswitchval{1,i} == "All Tracks"
                       dataswitchval{1,i} = [];
                    end
                    dataswitchval = dataswitchval(~cellfun('isempty',dataswitchval));
                end
                %find the data and files
                tmpdata = cell(size(dataswitchval,2),2);
                for i = 1:size(dataswitchval,2)
                    tmpfilename = dataswitchval{1,i};
                    for j = 1:size(app.ListBox.Items,2)
                        if string(tmpfilename) == string(app.ListBox.Items{1,j})
                            break
                        end
                    end
                    tmpdata{i,1} = app.PixelFrameTrackData{j,1};
                    tmpdata{i,2} = app.TrackData{j,1};
                end
                %concatenate the tracks
                PixelData = catTrackDataRename(tmpdata(:,1));
                UnitData = catTrackDataRename(tmpdata(:,2));

            else
                %single files
                if dataswitchval == "All Tracks" 
                    PixelData = app.ConcatPixelFrameTrackData;
                    UnitData = app.ConcatTrackData;
                elseif dataswitchval ~= "All Tracks"
                    for i = 1:size(app.ListBox.Items,2)
                        if string(dataswitchval) == string(app.ListBox.Items{1,i})
                            break
                        end
                    end
                PixelData = app.PixelFrameTrackData{i,1};
                UnitData = app.TrackData{i,1};
                end
            end

            calculateSaveStructureData(app,UnitData,PixelData);
        end
        
        function calculateSaveStructureData(app, UnitData, PixelData)
            app.UnitCalculatedDataStruct = calculateValuesAllAnalysisForSaveStructure(UnitData, app.UnitCalculatedDataStruct);
            app.PixelCalculatedDataStruct = calculateValuesAllAnalysisForSaveStructure(PixelData, app.PixelCalculatedDataStruct);
        end

        function managePlots(app)
            %central function that decides which plot will be shown and
            %with which properties.
            %prepare a clean slate
            if isa(app.Panel.Children, 'matlab.ui.control.UIAxes')
                app.OldFigSize = app.Panel.Children.Position;
            end
            manageExtraPanel(app);
            if isa(app.Panel.Children, 'matlab.graphics.layout.TiledChartLayout')
                delete(app.Panel.Children);
                %app.UIAxes = uiaxes(app.Panel, "Position", [1 3 723 646]);
                app.UIAxes = uiaxes(app.Panel, "Position",app.OldFigSize);
            end
            cla(app.UIAxes);
            view(app.UIAxes,2);
            try
                app.UIAxes.Legend.Visible = 0;
            catch
            end
            xticks(app.UIAxes, "auto");
            xticklabels(app.UIAxes, "auto");
            %controls which plot is shown by gui feedback
            tab = app.TabGroup.SelectedTab.Title;
            try
                if isa(app.PolAxes, 'matlab.graphics.axis.PolarAxes')
                    app.PolAxes.Visible = 0;
                    cla(app.PolAxes);
                    delete(app.PolAxes);
                end
            catch
            end
            app.UIAxes.Visible = 1;
            %collect the settings from the GUI
            Axes = app.UIAxes;
            binNumbers = app.NumberofBinsEditField.Value;
            if app.Switch.Value == "Pixel/Frame"
                isPixel = 1;
                SaveStructure = app.PixelCalculatedDataStruct;
            else
                isPixel = 0;
                SaveStructure = app.UnitCalculatedDataStruct;
            end
            performFit = app.PerformFitCheckBox.Value;
            if app.ActiveCheckBox.Value
                filterIDs = app.FilteredIDs;
            else
                filterIDs = [];
            end
            lengthUnit = app.Unitsused{1,1};
            pxsize = app.VisualisationWindow.ImportSettingsStruct.customUnits.Pixelsize;
            timeunit = app.VisualisationWindow.ImportSettingsStruct.customUnits.TimeUnit;
            timestep = app.VisualisationWindow.ImportSettingsStruct.customUnits.Timestep;
            typenames = app.VisualisationWindow.ImportSettingsStruct.diffstates;
            gaussDat = [];
            kernelDat = [];
            %check what to plot
            if tab == "Histograms"
                if app.JumpDistancesButton.Value
                    dimension = app.JumpDistancesButtonGroup.SelectedObject.Text;
                    [minv, maxv, gaussDat, kernelDat] = plotJumpDistance(Axes, binNumbers, SaveStructure, dimension, isPixel, lengthUnit, filterIDs, performFit);
                    setMinMaxValue(app, minv, maxv);
                elseif app.CumulativeJumpDistancesButton.Value
                    dimension = app.JumpDistancesButtonGroup.SelectedObject.Text;
                    [minv, maxv, gaussDat, kernelDat] = plotCumulativeJumpDistance(Axes, binNumbers, SaveStructure, dimension, isPixel, lengthUnit, filterIDs, performFit);
                    setMinMaxValue(app, minv, maxv);
                elseif app.MeanJumpDistancesButton.Value
                    dimension = app.JumpDistancesButtonGroup.SelectedObject.Text;
                    [minv, maxv, gaussDat, kernelDat] = plotMeanJumpDistance(Axes, binNumbers, SaveStructure, dimension, isPixel, lengthUnit, filterIDs, performFit);
                    setMinMaxValue(app, minv, maxv);
                elseif app.CumulativeMeanJumpDistancesButton.Value
                    dimension = app.JumpDistancesButtonGroup.SelectedObject.Text;
                    [minv, maxv, LinFitDat] = plotCumulativeMeanJumpDistance(Axes, binNumbers, SaveStructure, dimension, isPixel, lengthUnit, filterIDs, performFit);
                    setMinMaxValue(app, minv, maxv);
                    if ~isempty(LinFitDat)
                        FitValues = ["Offset", "m",];
                        T = table(FitValues.' ,LinFitDat.');
                        T.Properties.VariableNames = {'Value', 'Linear Fit'};
                        app.UITable.Data = T;
                        app.UITable.ColumnName = {'Value', 'Linear Fit'};
                    end
                elseif app.JumpAnglesButton.Value
                    app.UIAxes.Visible = 0;
                    app.PolAxes = polaraxes(app.Panel);
                    dimension = app.JumpAnglesButtonGroup.SelectedObject.Text;
                    plotJumpAngle(app.PolAxes, SaveStructure, dimension, filterIDs, performFit);
                elseif app.DiffusionParametersfromSwiftButton.Value
                    property = app.DiffParametersButtonGroup.SelectedObject.Text;
                    [minv, maxv, gaussDat, kernelDat] = plotDiffusionParametersFromSwift(Axes, binNumbers, SaveStructure, property, lengthUnit, filterIDs, performFit, timeunit);
                    setMinMaxValue(app, minv, maxv);
                elseif app.ConfinementRatioButton.Value
                    if app.Switch_3.Value == "2D"
                        dimension = "XY";
                    else
                        dimension = "XYZ";
                    end
                    if app.NetLengthButton.Value
                        property = "NetLength";
                    elseif app.LengthButton.Value
                        property = "AbsLength";
                    else
                        property = "ConfRatio";
                    end
                    [minv, maxv, gaussDat, kernelDat] = plotTrackRatios(Axes, binNumbers, SaveStructure, property, dimension, isPixel, lengthUnit, filterIDs, performFit);
                    setMinMaxValue(app, minv, maxv);
                elseif app.MSDfromInternMSDFitButton.Value
                    try
                        if app.Switch_2.Value == "2D"
                            dimension = "XY";
                        else
                            dimension = "XYZ";
                        end
                        if app.AlphaButton.Value
                            property = "Alpha";
                        elseif app.MSDButton_2.Value
                            property = "a";
                        elseif app.DButton_2.Value
                            property = "d";
                        elseif app.LinRButton.Value
                            property = "linR";
                        elseif app.LogRButton.Value
                            property = "logR";
                        end
                        %SaveStructure = app.PixelCalculatedDataStruct;
                        if isempty(SaveStructure.InternMSD.(dimension).(property))
                            %nothing
                        else
                            [minv, maxv, gaussDat, kernelDat] = plotInternMSDLyzer(Axes, binNumbers, SaveStructure, app.PixelFrameTrackData, app.PointstoFitEditField.Value, property, dimension, isPixel, lengthUnit, pxsize, timeunit, timestep, filterIDs, performFit);
                        end
                        setMinMaxValue(app, minv, maxv);
                    catch
                        f = msgbox("No MSD calculated yet!");
                    end
                elseif app.ClassificationfromSwiftButton.Value
                   [minv, maxv, gaussDat, kernelDat] = plotExternalTrackClassification(Axes, SaveStructure, typenames, filterIDs, performFit);
                   setMinMaxValue(app, minv, maxv);
                elseif app.NumberofJumpsPerTrackButton.Value
                   [minv, maxv, gaussDat, kernelDat] = plotNumberOfStepsPerTrack(Axes, binNumbers, SaveStructure, filterIDs, performFit);
                   setMinMaxValue(app, minv, maxv);
                elseif app.DiffusionTypeSwitchingFrequenciesButton.Value
                   [minv, maxv] = plotDiffusionTypeSwitch(Axes, SaveStructure, filterIDs);
                   setMinMaxValue(app, minv, maxv);
                end
                %collect the fit data if present
                if ~isempty(gaussDat)
                    FitValues = ["Mean", "Median", "Std", "Var", "NLL"];
                    GaussFit = round(gaussDat,2);
                    KernelFit = round(kernelDat,2);
                    T = table(FitValues.' ,GaussFit.' , KernelFit.');
                    T.Properties.VariableNames = {'Value', 'Gauss', 'Kernel'};
                    app.UITable.Data = T;
                    app.UITable.ColumnName = {'Value', 'GaussFit', 'KernelFit'};
                end
            elseif tab == "Statistics"
                if app.CumulativeTrackLengthButton.Value
                    [minv, maxv, LinFitDat] = plotCumulativeTrackLength(Axes, binNumbers, SaveStructure, filterIDs, performFit);
                    setMinMaxValue(app, minv, maxv);
                    if ~isempty(LinFitDat)
                        FitValues = ["Offset", "m",];
                        T = table(FitValues.' ,round(LinFitDat.',2));
                        T.Properties.VariableNames = {'Value', 'Linear Fit'};
                        app.UITable.Data = T;
                        app.UITable.ColumnName = {'Value', 'Linear Fit'};
                    end
                elseif app.AverageTrackLengthButton.Value
                    T = plotAveragedTrackLength(app.Panel, SaveStructure, filterIDs, isPixel, lengthUnit);
                    app.UITable.Data = T;
                    app.UITable.ColumnName = {'Origin', 'Min', 'Max', 'Median', 'Quantile'};
                elseif app.CalculateMSDDbyJumpDistancesButton.Value
                    [fitResults, dataResult, unitResults] = determineMSDandDfromJumpDistances(app.Panel, SaveStructure, isPixel, filterIDs, lengthUnit, timeunit, timestep, binNumbers, app.Is2dData);
                    if ~isempty(fitResults)
                        fitResults = round(fitResults,2);
                        FitValues = ["KolmogSmirnov-5%", "KolmogSmirnov-P", "MSD", "MSDErr", "D", "DErr"];
                        xfit = fitResults(1,:);
                        yfit = fitResults(2,:);
                        zfit = fitResults(3,:);
                        xyfit = fitResults(4,:);
                        xyzfit = fitResults(5,:);
                        datxfit = dataResult(1,:);
                        datyfit = dataResult(2,:);
                        datzfit = dataResult(3,:);
                        datxyfit = dataResult(4,:);
                        datxyzfit = dataResult(5,:);
                        T = table(FitValues.' ,xfit.' , yfit.', zfit.', xyfit.', xyzfit.',datxfit.' , datyfit.', datzfit.', datxyfit.', datxyzfit.', unitResults.');
                        T.Properties.VariableNames = {'FitValues', 'FitJumpDistX', 'FitJumpDistY', 'FitJumpDistZ', 'FitJumpDistXY', 'FitJumpDistXYZ', 'DatJumpDistX', 'DatJumpDistY', 'DatJumpDistZ', 'DatJumpDistXY', 'DatJumpDistXYZ', 'Unit'};
                        app.UITable.Data = T;
                        app.UITable.ColumnName = {'FitValues', 'FitJumpDistX', 'FitJumpDistY', 'FitJumpDistZ', 'FitJumpDistXY', 'FitJumpDistXYZ', 'DatJumpDistX', 'DatJumpDistY', 'DatJumpDistZ', 'DatJumpDistXY', 'DatJumpDistXYZ', 'Unit'};
                        
                        app.UnitCalculatedDataStruct.JumpDistMSD.X = xfit(3);
                        app.UnitCalculatedDataStruct.JumpDistMSD.Y = yfit(3);
                        app.UnitCalculatedDataStruct.JumpDistMSD.Z = zfit(3);
                        app.UnitCalculatedDataStruct.JumpDistMSD.XY = xyfit(3);
                        app.UnitCalculatedDataStruct.JumpDistMSD.XYZ = xyzfit(3);
                    end
                elseif app.CalculateDbyCumulativeJumpDistancesButton.Value
                    [xyout, xyzout] = calculateDbyCumulativeJumpDistances(app.Panel, SaveStructure, app.TrackingRadiusEditField.Value, app.DinitalestimatesEditField.Value, app.DiffusiontypesEditField.Value, filterIDs, binNumbers, lengthUnit, isPixel, timestep, app.Is2dData);
                    if ~isempty(xyout)
                        if isPixel
                            unitResults = ["px²/frame";"px²/frame"];
                        else
                           sqUnit = join([lengthUnit, "²"], "");
                           diffUnit = join([sqUnit "/" string(timestep) " " timeunit], "");
                           unitResults = [diffUnit;diffUnit];
                        end
                        
                        Dimensions = ["XY", "XYZ"];
                        xyDs = round(xyout.D,2);
                        xyDsErr = round(xyout.Derr,2);
                        xyAs = round(xyout.A,2);
                        xyAsErr = round(xyout.Aerr,2);
                        xyEffD = round(xyout.EffectiveD,2);
                        xyRsq = round(xyout.Ajd_R_square,2);
                        
                        xyzDs = round(xyzout.D,2);
                        xyzDsErr = round(xyzout.Derr,2);
                        xyzAs = round(xyzout.A,2);
                        xyzAsErr = round(xyzout.Aerr,2);
                        xyzEffD = round(xyzout.EffectiveD,2);
                        xyzRsq = round(xyzout.Ajd_R_square,2);

                        T = table(Dimensions.', [xyEffD; xyzEffD], [xyRsq; xyzRsq], [xyDs; xyzDs], [xyDsErr; xyzDsErr], [xyAs; xyzAs], [xyAsErr; xyzAsErr], unitResults);
                        if app.Is2dData
                            T(2,:) = [];
                        end
                        T.Properties.VariableNames = {'Dimension', 'Effective D', 'R²', 'D', 'D Error', 'A', 'A Error', 'Unit'};
                        app.UITable.Data = T;
                        app.UITable.ColumnName = {'Dimension', 'Effective D', 'R²', 'D', 'D Error', 'A', 'A Error', 'Unit'};
                    end
                elseif app.FitPopulationsbyMeanJumpDistanceButton.Value
                    dimension = app.JumpDistancesButtonGroup.SelectedObject.Text;
                    if size(dimension,2)<2
                        dimension = "XY";
                    else
                    end
                    gaussParameters = fitVelocityPopulationsByMeanJumpDistance(app.UIAxes, SaveStructure, dimension, binNumbers, filterIDs, app.DiffusiontypesEditField.Value, lengthUnit, isPixel);
                    app.UITable.Data = gaussParameters;
                    varNames = gaussParameters.Properties.VariableNames;
                    app.UITable.ColumnName = varNames;
                end
                if ~isempty(gaussDat)
                    FitValues = ["Mean", "Median", "Std", "Var"];
                    GaussFit = round(gaussDat,2);
                    KernelFit = round(kernelDat,2);
                    T = table(FitValues.' ,GaussFit.' , KernelFit.');
                    T.Properties.VariableNames = {'Value', 'Gauss', 'Kernel'};
                    app.UITable.Data = T;
                    app.UITable.ColumnName = {'Value', 'GaussFit', 'KernelFit'};
                end

            elseif tab == "Custom"
                if app.ConfinementvsmeanjumpdistanceButton.Value
                    app.CalculateButton.Enable = "on";
                    app.CalculateButton.Visible = "on";
                    
                elseif app.VolumeAreaoccupiedbyTracksButton.Value
                    dimension = app.JumpDistancesButtonGroup.SelectedObject.Text;
                    if app.ListBox.Value == "All Tracks"
                        if app.Switch.Value == "Pixel/Frame"
                            currentTrackData = app.ConcatPixelFrameTrackData;
                        else
                            currentTrackData = app.ConcatTrackData;
                        end
                    else
                        for i = 1:size(app.ListBox.Items,2)
                            if string(app.ListBox.Value) == string(app.ListBox.Items{i})
                                break
                            end
                        end
                        PixelData = app.PixelFrameTrackData{i,1};
                        UnitData = app.TrackData{i,1};
                        if app.Switch.Value == "Pixel/Frame"
                            currentTrackData = PixelData;
                        else
                            currentTrackData = UnitData;
                        end
                    end
                    methodChosen = app.Switch_4.Value;
                    [newAxes, alphaOut, outparas] = getVolumeAreaoccupiedByTracks(Axes, currentTrackData, dimension, filterIDs, methodChosen, app.AlphaRadiusEditField.Value, app.UseCheckBox.Value, isPixel, lengthUnit);
                    app.UIAxes = newAxes;
                    app.AlphaRadiusEditField.Value = alphaOut;
                    if ~isempty(outparas)
                        prop = outparas{1,1};
                        T = table(prop, outparas{1,2}, outparas{1,3});
                        T.Properties.VariableNames = {'Property', 'Value', 'Unit'};
                        app.UITable.Data = T;
                        app.UITable.ColumnName = {'Property', 'Value', 'Unit'};
                    end
                end
            end
            axis 'auto y'
            close all
            manageExportField(app);
        end

    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, visualisationwin)
            %set connections and get data
            app.VisualisationWindow = visualisationwin;
            app.ImportSettingsStruct = app.VisualisationWindow.ImportSettingsStruct;
            app.Unitsused = {app.VisualisationWindow.ImportSettingsStruct.customUnits.LengthUnit, app.VisualisationWindow.ImportSettingsStruct.customUnits.TimeUnit};
            app.DiffTypes = app.VisualisationWindow.ImportSettingsStruct.diffstates;
            app.TrackData = visualisationwin.getTrackDataForAnalysis();
            app.Is2dData = app.VisualisationWindow.Is2DData;
            renameUnitUnitFieldOfDataSwitch(app);
            %remove empty names
            for i = 1:size(app.TrackData)
                tmpsize = size(app.TrackData{i,1});
                if tmpsize(1) < 5
                    app.TrackData(i,:) = [];
                end
            end
            %prepare save structures
            app.UnitCalculatedDataStruct = app.setSaveStruct();
            app.PixelCalculatedDataStruct = app.setSaveStruct();
            %transform data into either unit/unit or pixel/pixel and add a
            %All Tracks option
            app.IsUnitFlag = 0;
            adjustDataAndScale(app);
            updateMovieList(app);
            concatData(app);
            %perform the calculations with the data
            selectDataToPlot(app);
            %plot the first graph
            manageExtraPanel(app);
            managePlots(app);
        end

        % Value changed function: ListBox
        function ListBoxValueChanged(app, event)
            app.UnitCalculatedDataStruct = app.setSaveStruct();
            app.PixelCalculatedDataStruct = app.setSaveStruct();
            selectDataToPlot(app);
            
            if app.ActiveCheckBox.Value
                grabFilter(app);
            end
            manageExtraPanel(app);
            managePlots(app);
        end

        % Selection changed function: ButtonGroup_2
        function ButtonGroup_2SelectionChanged(app, event)
            manageExtraPanel(app);
            managePlots(app);
        end

        % Value changed function: Switch
        function SwitchValueChanged(app, event)
            manageExtraPanel(app);
            managePlots(app);         
        end

        % Value changed function: LeftBorderEditField
        function LeftBorderEditFieldValueChanged(app, event)
            value = app.LeftBorderEditField.Value;
            updatePlot(app);
        end

        % Value changed function: RightBorderEditField
        function RightBorderEditFieldValueChanged(app, event)
            value = app.RightBorderEditField.Value;
            updatePlot(app);
        end

        % Value changed function: NumberofBinsEditField
        function NumberofBinsEditFieldValueChanged(app, event)
            value = app.NumberofBinsEditField.Value;
            adjustNumberSizeBinsbyNumber(app);
            managePlots(app);
        end

        % Selection changed function: JumpDistancesButtonGroup
        function JumpDistancesButtonGroupSelectionChanged(app, event)
            selectedButton = app.JumpDistancesButtonGroup.SelectedObject;
            managePlots(app);
        end

        % Selection changed function: JumpAnglesButtonGroup
        function JumpAnglesButtonGroupSelectionChanged(app, event)
            selectedButton = app.JumpAnglesButtonGroup.SelectedObject;
            managePlots(app);
        end

        % Selection changed function: DiffParametersButtonGroup
        function DiffParametersButtonGroupSelectionChanged(app, event)
            selectedButton = app.DiffParametersButtonGroup.SelectedObject;
            managePlots(app);
        end

        % Selection changed function: ButtonGroup
        function ButtonGroupSelectionChanged(app, event)
            selectedButton = app.ButtonGroup.SelectedObject;
            managePlots(app);
        end

        % Button pushed function: ComputeButton
        function ComputeButtonPushed(app, event)
            d = uiprogressdlg(app.AllTrackAnalysisUIFigure,'Title','Calculating MSD', 'Indeterminate','on');
            drawnow
            
            %determines which data is needed for the plotting step
            %and returns it for the plotting function
            %single file, all data, unit or pixel
            unitswitchval = app.Switch.Value;
            dataswitchval = app.ListBox.Value{1,1};
            
            %search for the data
            if dataswitchval == "All Tracks" 
                PixelData = app.ConcatPixelFrameTrackData;
            elseif dataswitchval ~= "All Tracks"
                for i = 1:size(app.ListBox.Items,1)
                    if dataswitchval == app.ListBox.Items{i}
                        break
                    end
                end
                PixelData = app.PixelFrameTrackData{i,1};
            end
            pxsize = app.VisualisationWindow.ImportSettingsStruct.customUnits.Pixelsize;
            timestep = app.VisualisationWindow.ImportSettingsStruct.customUnits.Timestep;
            fitValue = app.PointstoFitEditField.Value;
            [app.PixelCalculatedDataStruct, app.UnitCalculatedDataStruct] = calculateMSDClassic(PixelData, 2, fitValue, 0, app.PixelCalculatedDataStruct, app.UnitCalculatedDataStruct, timestep, pxsize);
            if mean(PixelData(:,5)) ~= 0
                [app.PixelCalculatedDataStruct, app.UnitCalculatedDataStruct]  = calculateMSDClassic(PixelData, 3, fitValue, 0, app.PixelCalculatedDataStruct, app.UnitCalculatedDataStruct, timestep, pxsize);
            end
            
            delete(d);
            managePlots(app);
        end

        % Selection changed function: ButtonGroup_3
        function ButtonGroup_3SelectionChanged(app, event)
            selectedButton = app.ButtonGroup_3.SelectedObject;
            managePlots(app);
        end

        % Button down function: StatisticsTab
        function StatisticsTabButtonDown(app, event)
            %managePlots(app);
        end

        % Button pushed function: DataFilterButton
        function DataFilterButtonPushed(app, event)
            if isa(app.DataFilterWindow, "DataFilter")
                app.DataFilterWindow.DataFilterUIFigure.Visible = "on";
            else
                app.DataFilterWindow = DataFilter(app);
            end        
        end

        % Close request function: AllTrackAnalysisUIFigure
        function AllTrackAnalysisUIFigureCloseRequest(app, event)
            delete(app.DataFilterWindow);
            delete(app)
        end

        % Selection changed function: ConfinementRatioButtonGroup
        function ConfinementRatioButtonGroupSelectionChanged(app, event)
            selectedButton = app.ConfinementRatioButtonGroup.SelectedObject;
            managePlots(app);
        end

        % Value changed function: Switch_3
        function Switch_3ValueChanged(app, event)
            value = app.Switch_3.Value;
            managePlots(app);
        end

        % Value changed function: ActiveCheckBox
        function ActiveCheckBoxValueChanged(app, event)
            value = app.ActiveCheckBox.Value;
            managePlots(app);
        end

        % Value changed function: SizeofBinsEditField
        function SizeofBinsEditFieldValueChanged(app, event)
            value = app.SizeofBinsEditField.Value;
            adjustNumberSizeBinsbySize(app)
            managePlots(app);
        end

        % Selection changed function: ButtonGroup_4
        function ButtonGroup_4SelectionChanged(app, event)
            selectedButton = app.ButtonGroup_4.SelectedObject;
            managePlots(app);
        end

        % Button down function: CustomTab
        function CustomTabButtonDown(app, event)
            %managePlots(app);
        end

        % Button down function: HistogramsTab
        function HistogramsTabButtonDown(app, event)
            %managePlots(app);
        end

        % Button down function: TabGroup
        function TabGroupButtonDown(app, event)
            managePlots(app);
        end

        % Button pushed function: SetSaveFolderButton
        function SetSaveFolderButtonPushed(app, event)
            app.SaveLocation = uigetdir;
            if ~isempty(app.SaveLocation)
                app.FoldernotSetLabel.Text = app.SaveLocation;
                cd(app.SaveLocation);
                filelist = app.ListBox.Items;
                filelist = string(filelist).';
                T = table(filelist);
                writetable(T, "FilesUsed.xlsx");
            end
        end

        % Button pushed function: SavetocsvButton
        function SavetocsvButtonPushed(app, event)
            data1 = app.UIAxes.Children(3).Data;
            data2 = app.UIAxes.Children(3).BinCounts;
            data3 = app.UIAxes.Children(3).BinEdges;
            writematrix(data1, join([app.FilenameEditField.Value "_InputData.csv"],""));
            writematrix(data2, join([app.FilenameEditField.Value "_BinCounts.csv"],""));
            writematrix(data3, join([app.FilenameEditField.Value "_BinEdges.csv"],""));
        end

        % Button pushed function: SavetofigButton
        function SavetofigButtonPushed(app, event)
            if isvalid(app.UIAxes) & ~isempty(app.UIAxes.Children)
                op = app.UIAxes.OuterPosition;
                fignew = figure('Visible','off', "Position", op); % Invisible figure
                newAxes = copyobj(app.UIAxes,fignew); % Copy the appropriate axes
                %set(newAxes,'Position',get(groot,'DefaultAxesPosition')); % The original position is copied too, so adjust it.
                set(fignew,'CreateFcn','set(gcbf,''Visible'',''on'')'); % Make it visible upon loading
                savefig(fignew, app.FilenameEditField.Value);
                delete(fignew);
            elseif isa(app.Panel.Children, 'matlab.graphics.layout.TiledChartLayout')
                op = app.Panel.OuterPosition;
                fignew = figure('Visible','off', "Position", op); % Invisible figu
                h = findobj(app.Panel, "type", "axes");
                s = copyobj(h, fignew);
                set(fignew,'CreateFcn','set(gcbf,''Visible'',''on'')'); % Make it visible upon loading
                savefig(fignew, app.FilenameEditField.Value);
                delete(fignew);
            elseif isvalid(app.PolAxes) & ~isempty(app.PolAxes)
                op = app.PolAxes.OuterPosition;
                fignew = figure('Visible','off', "Position", op); % Invisible figu
                h = app.PolAxes;
                s = copyobj(h, fignew);
                set(fignew,'CreateFcn','set(gcbf,''Visible'',''on'')'); % Make it visible upon loading
                savefig(fignew, app.FilenameEditField.Value);
                delete(fignew);
            end
        end

        % Button pushed function: SavetoSVGButton
        function SavetoSVGButtonPushed(app, event)
            if isvalid(app.UIAxes)& ~isempty(app.UIAxes.Children)
                op = app.UIAxes.OuterPosition;
                fignew = figure('Visible','off', "Position", op); % Invisible figure
                newAxes = copyobj(app.UIAxes,fignew); % Copy the appropriate axes
                saveas(fignew, app.FilenameEditField.Value,"svg");
                delete(fignew);
            elseif isa(app.Panel.Children, 'matlab.graphics.layout.TiledChartLayout')
                op = app.Panel.OuterPosition;
                fignew = figure('Visible','off', "Position", op); % Invisible figu
                h = findobj(app.Panel, "type", "axes");
                s = copyobj(h, fignew);
                saveas(fignew, app.FilenameEditField.Value,"svg");
                delete(fignew);
           elseif isvalid(app.PolAxes) & ~isempty(app.PolAxes)
                op = app.PolAxes.OuterPosition;
                fignew = figure('Visible','off'); % Invisible figu
                h = app.PolAxes;
                s = copyobj(h, fignew);
                saveas(fignew, app.FilenameEditField.Value,"svg");
                delete(fignew);
            end
        end

        % Value changed function: PerformFitCheckBox
        function PerformFitCheckBoxValueChanged(app, event)
            value = app.PerformFitCheckBox.Value;
            managePlots(app);
        end

        % Value changed function: TrackingRadiusEditField
        function TrackingRadiusEditFieldValueChanged(app, event)
            value = app.TrackingRadiusEditField.Value;
            managePlots(app);
        end

        % Value changed function: DiffusiontypesEditField
        function DiffusiontypesEditFieldValueChanged(app, event)
            value = app.DiffusiontypesEditField.Value;
            managePlots(app);
        end

        % Value changed function: DinitalestimatesEditField
        function DinitalestimatesEditFieldValueChanged(app, event)
            value = app.DinitalestimatesEditField.Value;
            managePlots(app);
        end

        % Selection changed function: DiffusionConstantSelectionButtonGroup
        function DiffusionConstantSelectionButtonGroupSelectionChanged(app, event)
            selectedButton = app.DiffusionConstantSelectionButtonGroup.SelectedObject;
            managePlots(app);
        end

        % Button pushed function: SaveTableButton
        function SaveTableButtonPushed(app, event)
            tableData = app.UITable.Data;
            filename = app.FilenameEditField.Value;
            extension = ".xlsx";
            filename = join([filename, extension],"");
            writetable(tableData, filename);
        end

        % Value changed function: Switch_4
        function Switch_4ValueChanged(app, event)
            value = app.Switch_4.Value;
            managePlots(app);
        end

        % Value changed function: AlphaRadiusEditField
        function AlphaRadiusEditFieldValueChanged(app, event)
            value = app.AlphaRadiusEditField.Value;
            managePlots(app);
        end

        % Value changed function: UseCheckBox
        function UseCheckBoxValueChanged(app, event)
            value = app.UseCheckBox.Value;
            managePlots(app);
        end

        % Window key press function: AllTrackAnalysisUIFigure
        function AllTrackAnalysisUIFigureWindowKeyPress(app, event)
            key = event.Key;
            if string(key) == "control"
                uiwait(app.AllTrackAnalysisUIFigure);
            end            
        end

        % Window key release function: AllTrackAnalysisUIFigure
        function AllTrackAnalysisUIFigureWindowKeyRelease(app, event)
            key = event.Key;
            if string(key) == "control"
                uiresume(app.AllTrackAnalysisUIFigure);
            end                
        end

        % Button pushed function: CalculateButton
        function CalculateButtonPushed(app, event)
             Axes = app.UIAxes;
            binNumbers = app.NumberofBinsEditField.Value;
            if app.Switch.Value == "Pixel/Frame"
                isPixel = 1;
                SaveStructure = app.PixelCalculatedDataStruct;
            else
                isPixel = 0;
                SaveStructure = app.UnitCalculatedDataStruct;
            end
            performFit = app.PerformFitCheckBox.Value;
            if app.ActiveCheckBox.Value
                filterIDs = app.FilteredIDs;
            else
                filterIDs = [];
            end
            lengthUnit = app.Unitsused{1,1};
            pxsize = app.VisualisationWindow.ImportSettingsStruct.customUnits.Pixelsize;
            timeunit = app.VisualisationWindow.ImportSettingsStruct.customUnits.TimeUnit;
            timestep = app.VisualisationWindow.ImportSettingsStruct.customUnits.Timestep;
            typenames = app.VisualisationWindow.ImportSettingsStruct.diffstates;
            gaussDat = [];
            kernelDat = [];
            
            dimension = convertStringsToChars(app.JumpDistancesButtonGroup.SelectedObject.Text);
                    if size(dimension) == 1
                        dimension = 'XY';
                    end
                    if app.MSDfitButton.Value
                        Dused = "internal MSD Fit";
                    elseif app.ExternalButton.Value
                        Dused = "external";
                    end
                    if app.MSDfitButton.Value && size(SaveStructure.InternMSD.TrackIDs, 1) < 1
                        dataswitchval = app.ListBox.Value;
                        %search for the data
                        if dataswitchval == "All Tracks" 
                            UnitData = app.ConcatTrackData;
                            PixelData = app.ConcatPixelFrameTrackData;
                        elseif dataswitchval ~= "All Tracks"
                            for i = 1:size(app.ListBox.Items,2)
                                if string(dataswitchval) == string(app.ListBox.Items{1,i})
                                    break
                                end
                            end
                            PixelData = app.PixelFrameTrackData{i,1};
                        end
                        if size(SaveStructure.InternMSD.TrackIDs,1) < 1 || ~isemtpy(filterIDs)
                            pxsize = app.VisualisationWindow.ImportSettingsStruct.customUnits.Pixelsize;
                            timestep = app.VisualisationWindow.ImportSettingsStruct.customUnits.Timestep;
                            fitValue = app.PointstoFitEditField.Value;
                            [app.PixelCalculatedDataStruct, app.UnitCalculatedDataStruct] = calculateMSDClassic(PixelData, 2, fitValue, 0, app.PixelCalculatedDataStruct, app.UnitCalculatedDataStruct, timestep, pxsize);
                            if mean(PixelData(:,5)) ~= 0
                                [app.PixelCalculatedDataStruct, app.UnitCalculatedDataStruct]  = calculateMSDClassic(PixelData, 3, fitValue, 0, app.PixelCalculatedDataStruct, app.UnitCalculatedDataStruct, timestep, pxsize);
                            end
                        end
                    end
                    d = uiprogressdlg(app.AllTrackAnalysisUIFigure,'Title','Computing Confinement Radius', 'Indeterminate','on');
                    drawnow
                    plotConfinementVsMeanJumpDistance(Axes, SaveStructure, dimension, filterIDs, Dused, isPixel, lengthUnit, timeunit, pxsize, timestep);
                    close(d);
        end

        % Button pushed function: ExportallcalculatedvaluesButton
        function ExportallcalculatedvaluesButtonPushed(app, event)
            save("UnitCalculatedDataStruct.mat", app.UnitCalculatedDataStruct);
            save("PixelCalculatedDataStruct.mat", app.PixelCalculatedDataStruct);
            writestruct(app.UnitCalculatedDataStruct,"UnitCalculatedStruc.xml");
            writestruct(app.PixelCalculatedDataStruct,"PixelCalculatedStruc.xml");
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.AllTrackAnalysisUIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {880, 880};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {356, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.AllTrackAnalysisUIFigure = uifigure('Visible', 'off');
            app.AllTrackAnalysisUIFigure.Name = 'All Track Analysis';
            app.AllTrackAnalysisUIFigure.Icon = '3rdPeak1.png';
            app.AllTrackAnalysisUIFigure.CloseRequestFcn = createCallbackFcn(app, @AllTrackAnalysisUIFigureCloseRequest, true);
            app.AllTrackAnalysisUIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.AllTrackAnalysisUIFigure.WindowKeyPressFcn = createCallbackFcn(app, @AllTrackAnalysisUIFigureWindowKeyPress, true);
            app.AllTrackAnalysisUIFigure.WindowKeyReleaseFcn = createCallbackFcn(app, @AllTrackAnalysisUIFigureWindowKeyRelease, true);
            app.GridLayout = uigridlayout(app.AllTrackAnalysisUIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.GridLayout2 = uigridlayout(app.LeftPanel);
            app.MovieSelectionPanel = uipanel(app.GridLayout2);
            app.MovieSelectionPanel.Title = 'Movie Selection';
            app.ListBox = uilistbox(app.MovieSelectionPanel);
            app.ListBox.Multiselect = 'on';
            app.ListBox.ValueChangedFcn = createCallbackFcn(app, @ListBoxValueChanged, true);
            app.ListBox.Tooltip = {'Select a file to analyze. Select "All" for all files in the list at once.'};
            app.ListBox.Value = {'Item 1'};
            app.HistogramSettingsPanel = uipanel(app.GridLayout2);
            app.HistogramSettingsPanel.Title = 'Histogram Settings';
            app.GridLayout5 = uigridlayout(app.HistogramSettingsPanel);
            app.LeftBorderEditFieldLabel = uilabel(app.GridLayout5);
            app.LeftBorderEditFieldLabel.Text = 'Left Border';
            app.LeftBorderEditField = uieditfield(app.GridLayout5, 'numeric');
            app.LeftBorderEditField.ValueChangedFcn = createCallbackFcn(app, @LeftBorderEditFieldValueChanged, true);
            app.LeftBorderEditField.Tooltip = {'Define the left border of the histogram/data.'};
            app.RightBorderEditFieldLabel = uilabel(app.GridLayout5);
            app.RightBorderEditFieldLabel.Text = 'Right Border';
            app.RightBorderEditField = uieditfield(app.GridLayout5, 'numeric');
            app.RightBorderEditField.ValueChangedFcn = createCallbackFcn(app, @RightBorderEditFieldValueChanged, true);
            app.RightBorderEditField.Tooltip = {'Define the right border of the histogram.'};
            app.RightBorderEditField.Value = 100;
            app.NumberofBinsEditFieldLabel = uilabel(app.GridLayout5);
            app.NumberofBinsEditFieldLabel.Text = 'Number of Bins';
            app.NumberofBinsEditField = uieditfield(app.GridLayout5, 'numeric');
            app.NumberofBinsEditField.Limits = [1 Inf];
            app.NumberofBinsEditField.ValueChangedFcn = createCallbackFcn(app, @NumberofBinsEditFieldValueChanged, true);
            app.NumberofBinsEditField.Tooltip = {'Define the number of bins for the histgoram.'};
            app.NumberofBinsEditField.Value = 20;
            app.SizeofBinsEditFieldLabel = uilabel(app.GridLayout5);
            app.SizeofBinsEditFieldLabel.Text = 'Size of Bins';
            app.SizeofBinsEditField = uieditfield(app.GridLayout5, 'numeric');
            app.SizeofBinsEditField.Limits = [1e-07 Inf];
            app.SizeofBinsEditField.ValueChangedFcn = createCallbackFcn(app, @SizeofBinsEditFieldValueChanged, true);
            app.SizeofBinsEditField.Tooltip = {'Define the size per bin for the histogram.'};
            app.SizeofBinsEditField.Value = 5;
            app.DataFilterPanel = uipanel(app.GridLayout2);
            app.DataFilterPanel.Title = 'Data Filter';
            app.GridLayout4 = uigridlayout(app.DataFilterPanel);
            app.DataFilterButton = uibutton(app.GridLayout4, 'push');
            app.DataFilterButton.ButtonPushedFcn = createCallbackFcn(app, @DataFilterButtonPushed, true);
            app.DataFilterButton.Tooltip = {'Opens the data filter window to filter the tracks based on their properties.'};
            app.DataFilterButton.Text = 'Data Filter';
            app.ActiveCheckBox = uicheckbox(app.GridLayout4);
            app.ActiveCheckBox.ValueChangedFcn = createCallbackFcn(app, @ActiveCheckBoxValueChanged, true);
            app.ActiveCheckBox.Tooltip = {'Activates or deactivates the data filter.'};
            app.ActiveCheckBox.Text = 'Active';
            app.ExportDataPanel = uipanel(app.GridLayout2);
            app.ExportDataPanel.Tooltip = {'Export panel to either save the table contents of the figures.'};
            app.ExportDataPanel.Title = 'Export Data';
            app.GridLayout3 = uigridlayout(app.ExportDataPanel);
            app.SetSaveFolderButton = uibutton(app.GridLayout3, 'push');
            app.SetSaveFolderButton.ButtonPushedFcn = createCallbackFcn(app, @SetSaveFolderButtonPushed, true);
            app.SetSaveFolderButton.Tooltip = {'Sets the save location for data to export to.'};
            app.SetSaveFolderButton.Text = 'Set Save Folder';
            app.FoldernotSetLabel = uilabel(app.GridLayout3);
            app.FoldernotSetLabel.Text = 'Folder not Set';
            app.SavetocsvButton = uibutton(app.GridLayout3, 'push');
            app.SavetocsvButton.ButtonPushedFcn = createCallbackFcn(app, @SavetocsvButtonPushed, true);
            app.SavetocsvButton.Tooltip = {'Saves the figure data into seperate csv files. Will save the counts in the histogram, the edges of the different bins and the input data.'};
            app.SavetocsvButton.Text = 'Save to csv';
            app.SavetofigButton = uibutton(app.GridLayout3, 'push');
            app.SavetofigButton.ButtonPushedFcn = createCallbackFcn(app, @SavetofigButtonPushed, true);
            app.SavetofigButton.Tooltip = {'Saves the current plot in the right panel as a .fig format to be opened in matlab.'};
            app.SavetofigButton.Text = 'Save to fig';
            app.SavetoSVGButton = uibutton(app.GridLayout3, 'push');
            app.SavetoSVGButton.ButtonPushedFcn = createCallbackFcn(app, @SavetoSVGButtonPushed, true);
            app.SavetoSVGButton.Tooltip = {'Saves the current figure in the right panel as a .svg to be used in Inkscape or Illustrator.'};
            app.SavetoSVGButton.Text = 'Save to SVG';
            app.SaveTableButton = uibutton(app.GridLayout3, 'push');
            app.SaveTableButton.ButtonPushedFcn = createCallbackFcn(app, @SaveTableButtonPushed, true);
            app.SaveTableButton.Tooltip = {'Saves the table below into an excel sheet.'};
            app.SaveTableButton.Text = 'Save Table';
            app.FilenameEditFieldLabel = uilabel(app.GridLayout3);
            app.FilenameEditFieldLabel.Text = 'Filename';
            app.FilenameEditField = uieditfield(app.GridLayout3, 'text');
            app.FilenameEditField.Value = 'FileToSave';
            app.GraphStatisticsPanel = uipanel(app.GridLayout2);
            app.GraphStatisticsPanel.Title = 'Graph Statistics';
            app.UITable = uitable(app.GraphStatisticsPanel);
            app.UITable.ColumnName = {'Column 1'; 'Column 2'; 'Column 3'; 'Column 4'};
            app.UITable.RowName = {};
            app.PropertiesPanel = uipanel(app.GridLayout2);
            app.PropertiesPanel.Title = 'Properties';
            app.TabGroup = uitabgroup(app.PropertiesPanel);
            app.TabGroup.ButtonDownFcn = createCallbackFcn(app, @TabGroupButtonDown, true);
            app.HistogramsTab = uitab(app.TabGroup);
            app.HistogramsTab.Tooltip = {'Allows to select a given property of the tracks to be visualized in the graph to the right.'};
            app.HistogramsTab.Title = 'Histograms';
            app.HistogramsTab.ButtonDownFcn = createCallbackFcn(app, @HistogramsTabButtonDown, true);
            app.ButtonGroup_2 = uibuttongroup(app.HistogramsTab);
            app.ButtonGroup_2.SelectionChangedFcn = createCallbackFcn(app, @ButtonGroup_2SelectionChanged, true);
            app.ButtonGroup_2.Title = ' ';
            app.JumpDistancesButton = uiradiobutton(app.ButtonGroup_2);
            app.JumpDistancesButton.Tooltip = {'Visualizes the Jump Distance Distribution. The additional options below the figure allow to select the dimensionality of the data.'};
            app.JumpDistancesButton.Text = 'Jump Distances';
            app.JumpDistancesButton.Value = true;
            app.CumulativeJumpDistancesButton = uiradiobutton(app.ButtonGroup_2);
            app.CumulativeJumpDistancesButton.Tooltip = {'Visualizes the Cumulative Jump Distance Distribution. The additional options below the figure allow to select the dimensionality of the data.'};
            app.CumulativeJumpDistancesButton.Text = 'Cumulative Jump Distances';
            app.DiffusionParametersfromSwiftButton = uiradiobutton(app.ButtonGroup_2);
            app.DiffusionParametersfromSwiftButton.Tooltip = {'Shows the Diffusion parameters from the Swift calculation or any other external software calculations.'};
            app.DiffusionParametersfromSwiftButton.Text = 'Diffusion Parameters from Swift';
            app.MeanJumpDistancesButton = uiradiobutton(app.ButtonGroup_2);
            app.MeanJumpDistancesButton.Tooltip = {'Visualizes the Mean Jump Distance Distribution. The additional options below the figure allow to select the dimensionality of the data.'};
            app.MeanJumpDistancesButton.Text = 'Mean Jump Distances';
            app.CumulativeMeanJumpDistancesButton = uiradiobutton(app.ButtonGroup_2);
            app.CumulativeMeanJumpDistancesButton.Tooltip = {'Visualizes Cumulative Mean the Jump Distance Distribution. The additional options below the figure allow to select the dimensionality of the data.'};
            app.CumulativeMeanJumpDistancesButton.Text = 'Cumulative Mean Jump Distances';
            app.JumpAnglesButton = uiradiobutton(app.ButtonGroup_2);
            app.JumpAnglesButton.Tooltip = {'Visualizes the Jump Angle Distribution of the data. The Additional Options allow for the selection of the dimensionality of the data.'};
            app.JumpAnglesButton.Text = 'Jump Angles';
            app.MSDfromInternMSDFitButton = uiradiobutton(app.ButtonGroup_2);
            app.MSDfromInternMSDFitButton.Tooltip = {'Calculates the MSD based on the localisation data anew in the software.'};
            app.MSDfromInternMSDFitButton.Text = 'MSD from Intern MSD Fit';
            app.ClassificationfromSwiftButton = uiradiobutton(app.ButtonGroup_2);
            app.ClassificationfromSwiftButton.Tooltip = {'Plots the classification of the diffusion type from Swift analysis or any other external software classification.'};
            app.ClassificationfromSwiftButton.Text = 'Classification from Swift';
            app.ConfinementRatioButton = uiradiobutton(app.ButtonGroup_2);
            app.ConfinementRatioButton.Tooltip = {'Visualizes the confinement ratio Distribution as well as the total distance or the netto distance of the tracks. The additional options below the figure allow to select the dimensionality of the data.'};
            app.ConfinementRatioButton.Text = 'Confinement Ratio';
            app.NumberofJumpsPerTrackButton = uiradiobutton(app.ButtonGroup_2);
            app.NumberofJumpsPerTrackButton.Tooltip = {'Visualizes the number of steps per track.'};
            app.NumberofJumpsPerTrackButton.Text = 'Number of Jumps Per Track';
            app.DiffusionTypeSwitchingFrequenciesButton = uiradiobutton(app.ButtonGroup_2);
            app.DiffusionTypeSwitchingFrequenciesButton.Tooltip = {'Will plot the distribution of subdiffusion patterns per track if subdiffusion patterns have been found by Swift or other tracking softwares.'};
            app.DiffusionTypeSwitchingFrequenciesButton.Text = 'Diffusion Type Switching Frequencies';
            app.StatisticsTab = uitab(app.TabGroup);
            app.StatisticsTab.Title = 'Statistics';
            app.StatisticsTab.ButtonDownFcn = createCallbackFcn(app, @StatisticsTabButtonDown, true);
            app.ButtonGroup_3 = uibuttongroup(app.StatisticsTab);
            app.ButtonGroup_3.SelectionChangedFcn = createCallbackFcn(app, @ButtonGroup_3SelectionChanged, true);
            app.ButtonGroup_3.Title = ' ';
            app.CumulativeTrackLengthButton = uiradiobutton(app.ButtonGroup_3);
            app.CumulativeTrackLengthButton.Tooltip = {'Will plot the cumulative track length of all tracks. Can help to identify espacially short or long tracks.'};
            app.CumulativeTrackLengthButton.Text = 'Cumulative Track Length';
            app.CumulativeTrackLengthButton.Value = true;
            app.AverageTrackLengthButton = uiradiobutton(app.ButtonGroup_3);
            app.AverageTrackLengthButton.Tooltip = {'Will plot the track length distribution of the number of steps, the absolute length and the netto length of the tracks.'};
            app.AverageTrackLengthButton.Text = 'Average Track Length';
            app.CalculateMSDDbyJumpDistancesButton = uiradiobutton(app.ButtonGroup_3);
            app.CalculateMSDDbyJumpDistancesButton.Tooltip = {'Will try to determine the MSD/D by a Gaussian fit to the jump distance distribution in X, Y, and Z.'};
            app.CalculateMSDDbyJumpDistancesButton.Text = 'Calculate MSD/D by Jump Distances';
            app.FitPopulationsbyMeanJumpDistanceButton = uiradiobutton(app.ButtonGroup_3);
            app.FitPopulationsbyMeanJumpDistanceButton.Tooltip = {'Will try to fit multiple gaussians onto the mean jump distance distribution to identify multipe populations of different speed. Is dependent on the number of bins to identify potentail subpopulations.'};
            app.FitPopulationsbyMeanJumpDistanceButton.Text = 'Fit Populations by Mean Jump Distance';
            app.CalculateDbyCumulativeJumpDistancesButton = uiradiobutton(app.ButtonGroup_3);
            app.CalculateDbyCumulativeJumpDistancesButton.Tooltip = {'Will try to determine D by fitting the cumulative jump distances. Fit is very dependent on the Number of Bins, as well as the tracking radius, the diffusion types and the inital estimates for D.'};
            app.CalculateDbyCumulativeJumpDistancesButton.Text = 'Calculate D by Cumulative Jump Distances';
            app.CalculateDbyCumulativeJumpDistancesPanel = uipanel(app.ButtonGroup_3);
            app.CalculateDbyCumulativeJumpDistancesPanel.Tooltip = {'If you want to calculate D by the cumulative jump distances you will need to adjust the values in this panel if the fit is not converging onto the data.'};
            app.CalculateDbyCumulativeJumpDistancesPanel.Title = 'Calculate D by Cumulative Jump Distances';
            app.GridLayout7 = uigridlayout(app.CalculateDbyCumulativeJumpDistancesPanel);
            app.TrackingRadiusEditFieldLabel = uilabel(app.GridLayout7);
            app.TrackingRadiusEditFieldLabel.Text = 'Tracking Radius';
            app.TrackingRadiusEditField = uieditfield(app.GridLayout7, 'text');
            app.TrackingRadiusEditField.ValueChangedFcn = createCallbackFcn(app, @TrackingRadiusEditFieldValueChanged, true);
            app.TrackingRadiusEditField.Value = '250';
            app.DiffusiontypesEditFieldLabel = uilabel(app.GridLayout7);
            app.DiffusiontypesEditFieldLabel.Text = 'Diffusion types';
            app.DiffusiontypesEditField = uieditfield(app.GridLayout7, 'text');
            app.DiffusiontypesEditField.ValueChangedFcn = createCallbackFcn(app, @DiffusiontypesEditFieldValueChanged, true);
            app.DiffusiontypesEditField.Value = '3';
            app.DinitalestimatesEditFieldLabel = uilabel(app.GridLayout7);
            app.DinitalestimatesEditFieldLabel.Text = 'D inital estimates';
            app.DinitalestimatesEditField = uieditfield(app.GridLayout7, 'text');
            app.DinitalestimatesEditField.ValueChangedFcn = createCallbackFcn(app, @DinitalestimatesEditFieldValueChanged, true);
            app.DinitalestimatesEditField.Value = '0.5, 0.8, 1';
            app.CustomTab = uitab(app.TabGroup);
            app.CustomTab.Title = 'Custom';
            app.CustomTab.ButtonDownFcn = createCallbackFcn(app, @CustomTabButtonDown, true);
            app.ButtonGroup_4 = uibuttongroup(app.CustomTab);
            app.ButtonGroup_4.SelectionChangedFcn = createCallbackFcn(app, @ButtonGroup_4SelectionChanged, true);
            app.ButtonGroup_4.Title = ' ';
            app.VolumeAreaoccupiedbyTracksButton = uiradiobutton(app.ButtonGroup_4);
            app.VolumeAreaoccupiedbyTracksButton.Tooltip = {'Determine the volume or area occupied by the tracks.'};
            app.VolumeAreaoccupiedbyTracksButton.Text = 'Volume/Area occupied by Tracks';
            app.ConfinementvsmeanjumpdistanceButton = uiradiobutton(app.ButtonGroup_4);
            app.ConfinementvsmeanjumpdistanceButton.Tooltip = {'Plots the confinement radius determined by the diffusion coeffiecent of immobile particles with the mean jump distance of the particles. can help to identify different groups in the immobile fraction. You can either use the interal calculated diffusion coefficient or an external one if present.'};
            app.ConfinementvsmeanjumpdistanceButton.Text = 'Confinement vs mean jump distance';
            app.ConfinementvsmeanjumpdistanceButton.Value = true;
            app.DiffusionConstantSelectionButtonGroup = uibuttongroup(app.ButtonGroup_4);
            app.DiffusionConstantSelectionButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @DiffusionConstantSelectionButtonGroupSelectionChanged, true);
            app.DiffusionConstantSelectionButtonGroup.Tooltip = {'Determines if the internal or external diffusion coefficient will be used for the confinement radius calculation.'};
            app.DiffusionConstantSelectionButtonGroup.Title = 'Diffusion Constant Selection';
            app.ExternalButton = uiradiobutton(app.DiffusionConstantSelectionButtonGroup);
            app.ExternalButton.Text = 'External';
            app.ExternalButton.Value = true;
            app.MSDfitButton = uiradiobutton(app.DiffusionConstantSelectionButtonGroup);
            app.MSDfitButton.Text = 'MSD fit';
            app.VolumeDeterminationPanel = uipanel(app.ButtonGroup_4);
            app.VolumeDeterminationPanel.Tooltip = {'Determines the method for the volume/area calcuation. The convex hull will form a very wide area that encolses the data while the alpha shape will rather perform a thight fit to the data.'; 'The AlphaShape function will try to determine the suitable raidus automatically. However if you feel the shape is off you can adjust the radius by yourself. '};
            app.VolumeDeterminationPanel.Title = 'Volume Determination';
            app.GridLayout6 = uigridlayout(app.VolumeDeterminationPanel);
            app.Switch_4 = uiswitch(app.GridLayout6, 'slider');
            app.Switch_4.Items = {'Convex Hull', 'Alpha Shape'};
            app.Switch_4.ValueChangedFcn = createCallbackFcn(app, @Switch_4ValueChanged, true);
            app.Switch_4.Value = 'Convex Hull';
            app.UseCheckBox = uicheckbox(app.GridLayout6);
            app.UseCheckBox.ValueChangedFcn = createCallbackFcn(app, @UseCheckBoxValueChanged, true);
            app.UseCheckBox.Text = 'Use';
            app.AlphaRadiusEditFieldLabel = uilabel(app.GridLayout6);
            app.AlphaRadiusEditFieldLabel.Text = 'Alpha Radius';
            app.AlphaRadiusEditField = uieditfield(app.GridLayout6, 'numeric');
            app.AlphaRadiusEditField.Limits = [0 Inf];
            app.AlphaRadiusEditField.ValueChangedFcn = createCallbackFcn(app, @AlphaRadiusEditFieldValueChanged, true);
            app.AlphaRadiusEditField.Tooltip = {'Shows the alpha radius used by the algorithm. You can also define it yourself if you have the feeling that the shape is off. Need to tick the "use" button so the manual raidus is applied to the function.'};
            app.AlphaRadiusEditField.Value = 1;
            app.ExportallcalculatedvaluesButton = uibutton(app.ButtonGroup_4, 'push');
            app.ExportallcalculatedvaluesButton.ButtonPushedFcn = createCallbackFcn(app, @ExportallcalculatedvaluesButtonPushed, true);
            app.ExportallcalculatedvaluesButton.Text = 'Export all calculated values';
            app.RightPanel = uipanel(app.GridLayout);
            app.GridLayout8 = uigridlayout(app.RightPanel);
            app.AdditionalOptionsPanel = uipanel(app.GridLayout8);
            app.AdditionalOptionsPanel.Title = 'Additional Options';
            app.GridLayout9 = uigridlayout(app.AdditionalOptionsPanel);
            app.JumpDistancesButtonGroup = uibuttongroup(app.GridLayout9);
            app.JumpDistancesButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @JumpDistancesButtonGroupSelectionChanged, true);
            app.JumpDistancesButtonGroup.Tooltip = {'Defines the dimensionality of the data for many plots. If for some plots only 2D or 3D is available, every single value (X, Y, Z) will also lead to the plotting of the 2D data.'};
            app.JumpDistancesButtonGroup.Title = 'Jump Distances';
            app.XButton = uiradiobutton(app.JumpDistancesButtonGroup);
            app.XButton.Text = 'X';
            app.XButton.Value = true;
            app.YButton = uiradiobutton(app.JumpDistancesButtonGroup);
            app.YButton.Text = 'Y';
            app.ZButton = uiradiobutton(app.JumpDistancesButtonGroup);
            app.ZButton.Text = 'Z';
            app.XYButton = uiradiobutton(app.JumpDistancesButtonGroup);
            app.XYButton.Text = 'XY';
            app.XYZButton = uiradiobutton(app.JumpDistancesButtonGroup);
            app.XYZButton.Text = 'XYZ';
            app.JumpAnglesButtonGroup = uibuttongroup(app.GridLayout9);
            app.JumpAnglesButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @JumpAnglesButtonGroupSelectionChanged, true);
            app.JumpAnglesButtonGroup.Tooltip = {'Defines the dimensionality of the jump angles data.'};
            app.JumpAnglesButtonGroup.Title = 'Jump Angles';
            app.XYButton_2 = uiradiobutton(app.JumpAnglesButtonGroup);
            app.XYButton_2.Text = 'XY';
            app.XYButton_2.Value = true;
            app.XYZButton_2 = uiradiobutton(app.JumpAnglesButtonGroup);
            app.XYZButton_2.Text = 'XYZ';
            app.MSDPanel = uipanel(app.GridLayout9);
            app.MSDPanel.Tooltip = {'Contorls the calculation of the internal MSD fit.'};
            app.MSDPanel.Title = 'MSD';
            app.PointstoFitEditFieldLabel = uilabel(app.MSDPanel);
            app.PointstoFitEditFieldLabel.Text = 'Points to Fit';
            app.PointstoFitEditField = uieditfield(app.MSDPanel, 'numeric');
            app.PointstoFitEditField.Limits = [2 Inf];
            app.PointstoFitEditField.Tooltip = {'Define the number of points the MSD fit will be applied to. Usually if should be about 10% of the average track length to perform a good fit.'};
            app.PointstoFitEditField.Value = 5;
            app.ComputeButton = uibutton(app.MSDPanel, 'push');
            app.ComputeButton.ButtonPushedFcn = createCallbackFcn(app, @ComputeButtonPushed, true);
            app.ComputeButton.Tooltip = {'Depending on the amount of data, the fit can take a while. Therefore you should trigger the fitting process yourself. '};
            app.ComputeButton.Text = 'Compute';
            app.Switch_2 = uiswitch(app.MSDPanel, 'slider');
            app.Switch_2.Items = {'2D', '3D'};
            app.Switch_2.Tooltip = {'Define if you want to have a fit in 2D or 3D'};
            app.Switch_2.Value = '2D';
            app.ButtonGroup = uibuttongroup(app.MSDPanel);
            app.ButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @ButtonGroupSelectionChanged, true);
            app.ButtonGroup.Tooltip = {'Define which property of the fit will be plotted. '};
            app.AlphaButton = uiradiobutton(app.ButtonGroup);
            app.AlphaButton.Text = 'Alpha';
            app.AlphaButton.Value = true;
            app.MSDButton_2 = uiradiobutton(app.ButtonGroup);
            app.MSDButton_2.Text = 'MSD';
            app.DButton_2 = uiradiobutton(app.ButtonGroup);
            app.DButton_2.Text = 'D';
            app.LinRButton = uiradiobutton(app.ButtonGroup);
            app.LinRButton.Text = 'LinR²';
            app.LogRButton = uiradiobutton(app.ButtonGroup);
            app.LogRButton.Text = 'LogR²';
            app.UnitsPanel = uipanel(app.GridLayout9);
            app.UnitsPanel.Tooltip = {'Define if the data will be shown in either pixel/Frame values or in the units defined during the import settings dialogue.'};
            app.UnitsPanel.Title = 'Units';
            app.Switch = uiswitch(app.UnitsPanel, 'slider');
            app.Switch.Items = {'Pixel/Frame', 'Unit/Unit'};
            app.Switch.ValueChangedFcn = createCallbackFcn(app, @SwitchValueChanged, true);
            app.Switch.Value = 'Pixel/Frame';
            app.DiffParametersButtonGroup = uibuttongroup(app.GridLayout9);
            app.DiffParametersButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @DiffParametersButtonGroupSelectionChanged, true);
            app.DiffParametersButtonGroup.Tooltip = {'Defines if either the MSD or D of the external calculation will be plotted.'};
            app.DiffParametersButtonGroup.Title = 'Diff Parameters';
            app.MSDButton = uiradiobutton(app.DiffParametersButtonGroup);
            app.MSDButton.Text = 'MSD';
            app.MSDButton.Value = true;
            app.DButton = uiradiobutton(app.DiffParametersButtonGroup);
            app.DButton.Text = 'D';
            app.ConfinementRatioButtonGroup = uibuttongroup(app.GridLayout9);
            app.ConfinementRatioButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @ConfinementRatioButtonGroupSelectionChanged, true);
            app.ConfinementRatioButtonGroup.Tooltip = {'Defines which part of the confinement ratio will be plotted. Either the ratio itself or the absolute lenght or the netto distance of the tracks.'};
            app.ConfinementRatioButtonGroup.Title = 'Confinement Ratio';
            app.RatioLengthNetLengthButton = uiradiobutton(app.ConfinementRatioButtonGroup);
            app.RatioLengthNetLengthButton.Text = 'Ratio (Length/NetLength)';
            app.RatioLengthNetLengthButton.Value = true;
            app.LengthButton = uiradiobutton(app.ConfinementRatioButtonGroup);
            app.LengthButton.Text = 'Length';
            app.NetLengthButton = uiradiobutton(app.ConfinementRatioButtonGroup);
            app.NetLengthButton.Text = 'Net Length';
            app.Switch_3 = uiswitch(app.ConfinementRatioButtonGroup, 'slider');
            app.Switch_3.Items = {'2D', '3D'};
            app.Switch_3.ValueChangedFcn = createCallbackFcn(app, @Switch_3ValueChanged, true);
            app.Switch_3.Value = '2D';
            app.FitPanel = uipanel(app.GridLayout9);
            app.FitPanel.Tooltip = {'Tick this to perform a fit onto the data if possile. Will show the values of the fit int he table to the left.'};
            app.FitPanel.Title = 'Fit';
            app.PerformFitCheckBox = uicheckbox(app.FitPanel);
            app.PerformFitCheckBox.ValueChangedFcn = createCallbackFcn(app, @PerformFitCheckBoxValueChanged, true);
            app.PerformFitCheckBox.Text = 'Perform Fit';
            app.CalculateButton = uibutton(app.GridLayout9, 'push');
            app.CalculateButton.ButtonPushedFcn = createCallbackFcn(app, @CalculateButtonPushed, true);
            app.CalculateButton.Text = 'Calculate';
            app.Panel = uipanel(app.GridLayout8);
            app.Panel.Title = 'Panel';
            app.UIAxes = uiaxes(app.Panel);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            % Show the figure after all components are created
            app.AllTrackAnalysisUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = AllTrackAnalysisWindow(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.AllTrackAnalysisUIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.AllTrackAnalysisUIFigure)
        end
    end
end