classdef ImportSettingsValidationWindow < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        ImportSettingsUIFigure          matlab.ui.Figure
        SetSettingsButton               matlab.ui.control.Button
        LoadSettingsButton              matlab.ui.control.Button
        Panel                           matlab.ui.container.Panel
        UnitDataConstellationButtonGroup  matlab.ui.container.ButtonGroup
        UnitUnitButton                  matlab.ui.control.RadioButton
        UnitFrameButton                 matlab.ui.control.RadioButton
        PixelUnitButton                 matlab.ui.control.RadioButton
        PixelFrameButton                matlab.ui.control.RadioButton
        TimeUnitEditField               matlab.ui.control.EditField
        TimeUnitEditFieldLabel          matlab.ui.control.Label
        MetricLengthUnitEditField       matlab.ui.control.EditField
        MetricLengthUnitEditFieldLabel  matlab.ui.control.Label
        TimestepinUnitEditField         matlab.ui.control.NumericEditField
        TimestepinUnitEditFieldLabel    matlab.ui.control.Label
        PixelsizeinUnitEditField        matlab.ui.control.NumericEditField
        PixelsizeinUnitEditFieldLabel   matlab.ui.control.Label
        SaveSettingsButton              matlab.ui.control.Button
        CustomDataTypeRowContentsPanel  matlab.ui.container.Panel
        DiffusionTypeEditField          matlab.ui.control.NumericEditField
        DiffusionTypeEditFieldLabel     matlab.ui.control.Label
        SkipheaderLinesEditField        matlab.ui.control.NumericEditField
        SkipheaderLinesEditFieldLabel   matlab.ui.control.Label
        RowNumberLabel_2                matlab.ui.control.Label
        RowNumberLabel                  matlab.ui.control.Label
        SeparatorEditField              matlab.ui.control.EditField
        SeparatorEditFieldLabel         matlab.ui.control.Label
        ErrorMSDEditField               matlab.ui.control.NumericEditField
        ErrorMSDEditFieldLabel          matlab.ui.control.Label
        IntensityEditField              matlab.ui.control.NumericEditField
        IntensityEditFieldLabel         matlab.ui.control.Label
        DerrEditField                   matlab.ui.control.NumericEditField
        DerrEditFieldLabel              matlab.ui.control.Label
        DEditField                      matlab.ui.control.NumericEditField
        DEditFieldLabel                 matlab.ui.control.Label
        MSDEditField                    matlab.ui.control.NumericEditField
        MSDEditFieldLabel               matlab.ui.control.Label
        ZPositionEditField              matlab.ui.control.NumericEditField
        ZPositionEditFieldLabel         matlab.ui.control.Label
        YPositionEditField              matlab.ui.control.NumericEditField
        YPositionEditFieldLabel         matlab.ui.control.Label
        XPositionEditField              matlab.ui.control.NumericEditField
        XPositionEditFieldLabel         matlab.ui.control.Label
        FrameNrEditField                matlab.ui.control.NumericEditField
        FrameNrEditFieldLabel           matlab.ui.control.Label
        TrackIDEditField                matlab.ui.control.NumericEditField
        TrackIDEditFieldLabel           matlab.ui.control.Label
        ImportSettingsLabel             matlab.ui.control.Label
        ImportDataTypeButtonGroup       matlab.ui.container.ButtonGroup
        CustomLocalisationscsvButton    matlab.ui.control.RadioButton
        CustomLocalisationsmatButton    matlab.ui.control.RadioButton
        SMAPLocalisationsButton         matlab.ui.control.RadioButton
        CustommatButton                 matlab.ui.control.RadioButton
        CustomCSVButton                 matlab.ui.control.RadioButton
        SwiftMinDataCSVButton           matlab.ui.control.RadioButton
        SwiftAllDataCSVButton           matlab.ui.control.RadioButton
    end

    
    properties (Access = private)
        ValidationWindow % Link to Validation Window
        ImportSettingsStruct % A Struct to save all the import settings set on this window
        OldSettingsStruct %Struct that might hold old settings that have been used before in this session
    end
    
    methods (Access = private)
        
        function app = checkImportDataType(app)
            %Depending on the file type used, one does need the extra
            %options or not, so the fields needed will be controlled by
            %this function
            if app.SwiftAllDataCSVButton.Value == 1
                app.CustomDataTypeRowContentsPanel.Enable = "off";
                children = app.CustomDataTypeRowContentsPanel.Children;
                set(children, "Enable", "off");
            end
            if app.SwiftMinDataCSVButton.Value == 1
                app.CustomDataTypeRowContentsPanel.Enable = "off";
                children = app.CustomDataTypeRowContentsPanel.Children;
                set(children, "Enable", "off");
            end
            if app.CustomCSVButton.Value == 1
                app.CustomDataTypeRowContentsPanel.Enable = "on";
                children = app.CustomDataTypeRowContentsPanel.Children;
                set(children, "Enable", "on");
            end
            if app.CustommatButton.Value == 1
                app.CustomDataTypeRowContentsPanel.Enable = "on";
                children = app.CustomDataTypeRowContentsPanel.Children;
                set(children, "Enable", "on");
            end
            if app.SMAPLocalisationsButton.Value == 1
                app.CustomDataTypeRowContentsPanel.Enable = "off";
                children = app.CustomDataTypeRowContentsPanel.Children;
                set(children, "Enable", "off");
            end
            if app.CustomLocalisationsmatButton.Value == 1
                app.CustomDataTypeRowContentsPanel.Enable = "on";
                children = app.CustomDataTypeRowContentsPanel.Children;
                set(children, "Enable", "on");
                app.TrackIDEditField.Enable = "off";
                app.MSDEditField.Enable = "off";
                app.ErrorMSDEditField.Enable = "off";
                app.DEditField.Enable = "off";
                app.DerrEditField.Enable = "off";
                app.DiffusionTypeEditField.Enable = "off";
            end
            if app.CustomLocalisationscsvButton.Value == 1
                app.CustomDataTypeRowContentsPanel.Enable = "on";
                children = app.CustomDataTypeRowContentsPanel.Children;
                set(children, "Enable", "on");
                app.TrackIDEditField.Enable = "off";
                app.MSDEditField.Enable = "off";
                app.ErrorMSDEditField.Enable = "off";
                app.DEditField.Enable = "off";
                app.DerrEditField.Enable = "off";
                app.DiffusionTypeEditField.Enable = "off";
            end
        end
        
        function app = adjustUnitName(app)
            %Puts the name of the units set in the edit field in the
            %respective labels of the app to make association more easily
            %(hopefully)
            metricUnit = app.MetricLengthUnitEditField.Value;
            timeUnit = app.TimeUnitEditField.Value;
            %Split the strings
            txt1 = split(convertCharsToStrings(app.PixelUnitButton.Text),"/");
            txt2 = split(convertCharsToStrings(app.UnitFrameButton.Text),"/");
            txt3 = split(convertCharsToStrings(app.UnitUnitButton.Text),"/");
            txt4 = split(convertCharsToStrings(app.PixelsizeinUnitEditFieldLabel.Text));
            txt5 = split(convertCharsToStrings(app.TimestepinUnitEditFieldLabel.Text));
            %add the unit name
            txt1(2) = timeUnit;
            txt2(1) = metricUnit;
            txt3(1) = metricUnit;
            txt3(2) = timeUnit;
            txt4(end) = metricUnit;
            txt5(end) = timeUnit;
            %join the strings and put them into place
            txt1 = join(txt1, "/");
            txt2 = join(txt2, "/");
            txt3 = join(txt3, "/");
            txt4 = join(txt4);
            txt5 = join(txt5);
            app.PixelUnitButton.Text = convertStringsToChars(txt1);
            app.UnitFrameButton.Text = convertStringsToChars(txt2);
            app.UnitUnitButton.Text = convertStringsToChars(txt3);
            app.PixelsizeinUnitEditFieldLabel.Text = convertStringsToChars(txt4);
            app.TimestepinUnitEditFieldLabel.Text = convertStringsToChars(txt5);
        end
        
        function app = checkSettingsChanged(app)
            %check if settings have changed. Will lead to clearance of data
            %in main app to be ready for new data type
            if isa(app.OldSettingsStruct, "struct")
                %there is an old setting
                if isequaln(app.OldSettingsStruct, app.ImportSettingsStruct)
                    %its the same as the old one
                    app.ValidationWindow.setSettingsChanged(0);
                else
                    %settings have changed
                    app.ValidationWindow.setSettingsChanged(1);
                end
            else
                %no settings set before, therefore no change
                app.ValidationWindow.setSettingsChanged(0);
            end
        end
        
        function structure = packImportSettings(app)
            %Packs all the settings in the window into one struct that will
            %be passed back to the Validation app if the window is closed
            %via save-button
            cont.type = app.ImportDataTypeButtonGroup.SelectedObject.Text;
            %Units
            cont.customUnits.DataType = app.UnitDataConstellationButtonGroup.SelectedObject.Text;
            cont.customUnits.LengthUnit = app.MetricLengthUnitEditField.Value;
            cont.customUnits.TimeUnit = app.TimeUnitEditField.Value;
            cont.customUnits.Pixelsize = app.PixelsizeinUnitEditField.Value;
            cont.customUnits.Timestep = app.TimestepinUnitEditField.Value;
            %Custom CSV, mat file structure
            %Import for CSV
            cont.separator = app.SeparatorEditField.Value;
            cont.headlerlines = app.SkipheaderLinesEditField.Value;
            %Column organisation
            cont.trackid = app.TrackIDEditField.Value;
            cont.framenr = app.FrameNrEditField.Value;
            cont.xpos = app.XPositionEditField.Value;
            cont.d = app.DEditField.Value;
            cont.ypos = app.YPositionEditField.Value;
            cont.derr = app.DerrEditField.Value;
            cont.zpos = app.ZPositionEditField.Value;
            cont.int = app.IntensityEditField.Value;
            cont.msd = app.MSDEditField.Value;
            cont.msderr = app.ErrorMSDEditField.Value;
            cont.difftype = app.DiffusionTypeEditField.Value;
            
            structure = cont;
        end
        
        function loadSettings(app, loadfile)
            %make sure that a settings file is actually loaded
            if isfield(loadfile,"trackid") && isfield(loadfile,"customUnits")
            else
                error("Not a import settings file!")
            end
            
            app.ImportSettingsStruct = loadfile;
            
            %Change the settings in the window
            switch loadfile.type
                case "Swift-AllData CSV"
                    app.ImportDataTypeButtonGroup.SelectedObject = app.SwiftAllDataCSVButton;
                case "Swift-MinData CSV"
                    app.ImportDataTypeButtonGroup.SelectedObject = app.SwiftMinDataCSVButton;
                case "Custom CSV"
                    app.ImportDataTypeButtonGroup.SelectedObject = app.CustomCSVButton;
                case "Custom .mat"
                    app.ImportDataTypeButtonGroup.SelectedObject = app.CustommatButton;
                case "SMAP-Localisations"
                    app.ImportDataTypeButtonGroup.SelectedObject = app.SMAPLocalisationsButton;
                case "Custom Localisations mat"
                    app.ImportDataTypeButtonGroup.SelectedObject = app.CustomLocalisationsmatButton;
                case "Custom Localisations csv"
                    app.ImportDataTypeButtonGroup.SelectedObject = app.CustomLocalisationscsvButton;
            end
            %adjust the values in the window according to the saved
            %settings
            impdattype = split(convertCharsToStrings(loadfile.customUnits.DataType),"/");
            comparison = strcmp(impdattype, ["Pixel"; "Frame"]);
            if comparison %both a true
                app.UnitDataConstellationButtonGroup.SelectedObject = app.PixelFrameButton;
            elseif comparison(1) & (~comparison(2))%Pixel/Unit
                app.UnitDataConstellationButtonGroup.SelectedObject = app.PixelUnitButton;
            elseif (~comparison(1)) & comparison(2)%Unit/Frame
                app.UnitDataConstellationButtonGroup.SelectedObject = app.UnitFrameButton;
            else
                app.UnitDataConstellationButtonGroup.SelectedObject = app.UnitUnitButton;
            end
            app.MetricLengthUnitEditField.Value = loadfile.customUnits.LengthUnit;
            app.TimeUnitEditField.Value = loadfile.customUnits.TimeUnit;
            app.PixelsizeinUnitEditField.Value = loadfile.customUnits.Pixelsize;
            app.TimestepinUnitEditField.Value = loadfile.customUnits.Timestep;
            
            app.SeparatorEditField.Value = loadfile.separator;
            app.SkipheaderLinesEditField.Value = loadfile.headlerlines;

            app.TrackIDEditField.Value = loadfile.trackid;
            app.FrameNrEditField.Value = loadfile.framenr;
            app.XPositionEditField.Value = loadfile.xpos;
            app.DEditField.Value = loadfile.d;
            app.YPositionEditField.Value = loadfile.ypos;
            app.DerrEditField.Value = loadfile.derr;
            app.ZPositionEditField.Value = loadfile.zpos;
            app.IntensityEditField.Value = loadfile.int;
            app.MSDEditField.Value = loadfile.msd;
            app.ErrorMSDEditField.Value = loadfile.msderr;
            app.DiffusionTypeEditField.Value = loadfile.difftype;
        end
    end
    
    methods (Access = public)
        
        function SettingsStruct = getSettings(app)
            SettingsStruct = app.ImportSettingsStruct;
        end
        
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, visualisationwin)
            %set link to calling app
            app.ValidationWindow = visualisationwin;
            %Set the unit names
            app.adjustUnitName();
            %For standart swift import, the custom data type field is not needed
            app.CustomDataTypeRowContentsPanel.Enable = "off";
            children = app.CustomDataTypeRowContentsPanel.Children;
            set(children, "Enable", "off");
        end

        % Close request function: ImportSettingsUIFigure
        function ImportSettingsUIFigureCloseRequest(app, event)
            app.ValidationWindow.ImportSettingsButton.Enable = "on";
            app.ImportSettingsUIFigure.Visible = "off";
            %delete(app)
        end

        % Button pushed function: SaveSettingsButton
        function SaveSettingsButtonPushed(app, event)
            app.ImportSettingsStruct = packImportSettings(app);
            %saves the settings also in a folder of choise so one might
            %import it later.
            filterspec = {'*.mat'};
            [f, p] = uiputfile(filterspec);
            % Make sure user didn't cancel uigetfile dialog
            if ~ischar(p)
                error("No file selected")
            end

            fulfile = fullfile(p,f);
            settings = app.ImportSettingsStruct;
            save(fulfile, "settings");
            
            checkSettingsChanged(app);
            app.OldSettingsStruct = app.ImportSettingsStruct;
            
            app.ValidationWindow.ImportSettingsButton.Enable = "on";
            app.ValidationWindow.BrowseButton_2.Enable = "off";
            app.ImportSettingsUIFigure.Visible = "off";
            
            app.ValidationWindow.updateSettings();
        end

        % Selection changed function: ImportDataTypeButtonGroup
        function ImportDataTypeButtonGroupSelectionChanged(app, event)
            checkImportDataType(app);
        end

        % Button pushed function: LoadSettingsButton
        function LoadSettingsButtonPushed(app, event)
            % Display uigetfile dialog
            filterspec = {'*.mat'};
            [f, p] = uigetfile(filterspec);
            % Make sure user didn't cancel uigetfile dialog
            if ~ischar(p)
            error("No file selected")
            end
            loadfile = load(fullfile(p,f));
            loadfile = loadfile.settings;
            %checks if settings are different
            try
                app.OldSettingsStruct = app.ImportSettingsStruct;
            end
            loadSettings(app, loadfile);
            checkSettingsChanged(app);
            
            checkImportDataType(app);
            app.adjustUnitName();
        end

        % Button pushed function: SetSettingsButton
        function SetSettingsButtonPushed(app, event)
            %Packs the current settings
            app.ImportSettingsStruct = packImportSettings(app);
            %checks if settings have changed
            checkSettingsChanged(app);
            app.OldSettingsStruct = app.ImportSettingsStruct;
            %enables the gui and hides the settings
            app.ValidationWindow.ImportSettingsButton.Enable = "on";
            app.ValidationWindow.BrowseButton_2.Enable = "off";
            app.ImportSettingsUIFigure.Visible = "off";
            %sends the settings to calling app
            app.ValidationWindow.updateSettings();
        end

        % Value changed function: MetricLengthUnitEditField
        function MetricLengthUnitEditFieldValueChanged(app, event)
            value = app.MetricLengthUnitEditField.Value;
            app.adjustUnitName();
        end

        % Value changed function: TimeUnitEditField
        function TimeUnitEditFieldValueChanged(app, event)
            value = app.TimeUnitEditField.Value;
            app.adjustUnitName();
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.ImportSettingsUIFigure = uifigure('Visible', 'off');
            app.ImportSettingsUIFigure.Name = 'Import Settings';
            app.ImportSettingsUIFigure.Icon = 'auxin6_3rdPeak1.png';
            app.ImportSettingsUIFigure.CloseRequestFcn = createCallbackFcn(app, @ImportSettingsUIFigureCloseRequest, true);
            app.ImportDataTypeButtonGroup = uibuttongroup(app.ImportSettingsUIFigure);
            app.ImportDataTypeButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @ImportDataTypeButtonGroupSelectionChanged, true);
            app.ImportDataTypeButtonGroup.Title = 'Import Data Type';
            app.SwiftAllDataCSVButton = uiradiobutton(app.ImportDataTypeButtonGroup);
            app.SwiftAllDataCSVButton.Tooltip = {'All-Output Data Type from the Swift Tracking Software.'};
            app.SwiftAllDataCSVButton.Text = 'Swift-AllData CSV';
            app.SwiftAllDataCSVButton.Value = true;
            app.SwiftMinDataCSVButton = uiradiobutton(app.ImportDataTypeButtonGroup);
            app.SwiftMinDataCSVButton.Tooltip = {'Minimal Output Data Type from the Swift Tracking Software.'};
            app.SwiftMinDataCSVButton.Text = 'Swift-MinData CSV';
            app.CustomCSVButton = uiradiobutton(app.ImportDataTypeButtonGroup);
            app.CustomCSVButton.Tooltip = {'Will enalbe the custom section below to allow for loading of custom file data.'};
            app.CustomCSVButton.Text = 'Custom CSV';
            app.CustommatButton = uiradiobutton(app.ImportDataTypeButtonGroup);
            app.CustommatButton.Tooltip = {'Will enable the custom data type area below to allow for custom file loading'; ''};
            app.CustommatButton.Text = 'Custom .mat';
            app.SMAPLocalisationsButton = uiradiobutton(app.ImportDataTypeButtonGroup);
            app.SMAPLocalisationsButton.Tooltip = {'All-Output Data Type from the Swift Tracking Software.'};
            app.SMAPLocalisationsButton.Text = 'SMAP-Localisations';
            app.CustomLocalisationsmatButton = uiradiobutton(app.ImportDataTypeButtonGroup);
            app.CustomLocalisationsmatButton.Tooltip = {'All-Output Data Type from the Swift Tracking Software.'};
            app.CustomLocalisationsmatButton.Text = 'Custom Localisations mat';
            app.CustomLocalisationscsvButton = uiradiobutton(app.ImportDataTypeButtonGroup);
            app.CustomLocalisationscsvButton.Tooltip = {'All-Output Data Type from the Swift Tracking Software.'};
            app.CustomLocalisationscsvButton.Text = 'Custom Localisations csv';
            app.ImportSettingsLabel = uilabel(app.ImportSettingsUIFigure);
            app.ImportSettingsLabel.Text = 'Import Settings';
            app.CustomDataTypeRowContentsPanel = uipanel(app.ImportSettingsUIFigure);
            app.CustomDataTypeRowContentsPanel.Title = 'Custom Data Type - Row Contents';
            app.TrackIDEditFieldLabel = uilabel(app.CustomDataTypeRowContentsPanel);
            app.TrackIDEditFieldLabel.Text = 'Track ID';
            app.TrackIDEditField = uieditfield(app.CustomDataTypeRowContentsPanel, 'numeric');
            app.TrackIDEditField.Value = 1;
            app.FrameNrEditFieldLabel = uilabel(app.CustomDataTypeRowContentsPanel);
            app.FrameNrEditFieldLabel.Text = 'Frame Nr.';
            app.FrameNrEditField = uieditfield(app.CustomDataTypeRowContentsPanel, 'numeric');
            app.FrameNrEditField.Value = 2;
            app.XPositionEditFieldLabel = uilabel(app.CustomDataTypeRowContentsPanel);
            app.XPositionEditFieldLabel.Text = 'X Position';
            app.XPositionEditField = uieditfield(app.CustomDataTypeRowContentsPanel, 'numeric');
            app.XPositionEditField.Value = 3;
            app.YPositionEditFieldLabel = uilabel(app.CustomDataTypeRowContentsPanel);
            app.YPositionEditFieldLabel.Text = 'Y Position';
            app.YPositionEditField = uieditfield(app.CustomDataTypeRowContentsPanel, 'numeric');
            app.YPositionEditField.Value = 4;
            app.ZPositionEditFieldLabel = uilabel(app.CustomDataTypeRowContentsPanel);
            app.ZPositionEditFieldLabel.Text = 'Z Position';
            app.ZPositionEditField = uieditfield(app.CustomDataTypeRowContentsPanel, 'numeric');
            app.ZPositionEditField.Value = 5;
            app.MSDEditFieldLabel = uilabel(app.CustomDataTypeRowContentsPanel);
            app.MSDEditFieldLabel.Text = 'MSD';
            app.MSDEditField = uieditfield(app.CustomDataTypeRowContentsPanel, 'numeric');
            app.MSDEditField.Value = 32;
            app.DEditFieldLabel = uilabel(app.CustomDataTypeRowContentsPanel);
            app.DEditFieldLabel.Text = 'D';
            app.DEditField = uieditfield(app.CustomDataTypeRowContentsPanel, 'numeric');
            app.DEditField.Value = 9;
            app.DerrEditFieldLabel = uilabel(app.CustomDataTypeRowContentsPanel);
            app.DerrEditFieldLabel.Text = 'Derr';
            app.DerrEditField = uieditfield(app.CustomDataTypeRowContentsPanel, 'numeric');
            app.DerrEditField.Value = 10;
            app.IntensityEditFieldLabel = uilabel(app.CustomDataTypeRowContentsPanel);
            app.IntensityEditFieldLabel.Text = 'Intensity';
            app.IntensityEditField = uieditfield(app.CustomDataTypeRowContentsPanel, 'numeric');
            app.IntensityEditField.Value = 11;
            app.ErrorMSDEditFieldLabel = uilabel(app.CustomDataTypeRowContentsPanel);
            app.ErrorMSDEditFieldLabel.Text = 'Error MSD';
            app.ErrorMSDEditField = uieditfield(app.CustomDataTypeRowContentsPanel, 'numeric');
            app.ErrorMSDEditField.Value = 33;
            app.SeparatorEditFieldLabel = uilabel(app.CustomDataTypeRowContentsPanel);
            app.SeparatorEditFieldLabel.Text = 'Separator';
            app.SeparatorEditField = uieditfield(app.CustomDataTypeRowContentsPanel, 'text');
            app.SeparatorEditField.Value = ',';
            app.RowNumberLabel = uilabel(app.CustomDataTypeRowContentsPanel);
            app.RowNumberLabel.Text = 'Row Number';
            app.RowNumberLabel_2 = uilabel(app.CustomDataTypeRowContentsPanel);
            app.RowNumberLabel_2.Text = 'Row Number';
            app.SkipheaderLinesEditFieldLabel = uilabel(app.CustomDataTypeRowContentsPanel);
            app.SkipheaderLinesEditFieldLabel.Text = 'Skip header Lines';
            app.SkipheaderLinesEditField = uieditfield(app.CustomDataTypeRowContentsPanel, 'numeric');
            app.SkipheaderLinesEditField.Value = 9;
            app.DiffusionTypeEditFieldLabel = uilabel(app.CustomDataTypeRowContentsPanel);
            app.DiffusionTypeEditFieldLabel.Text = 'Diffusion Type';
            app.DiffusionTypeEditField = uieditfield(app.CustomDataTypeRowContentsPanel, 'numeric');
            app.DiffusionTypeEditField.Value = 34;
            app.SaveSettingsButton = uibutton(app.ImportSettingsUIFigure, 'push');
            app.SaveSettingsButton.ButtonPushedFcn = createCallbackFcn(app, @SaveSettingsButtonPushed, true);
            app.SaveSettingsButton.Tooltip = {'Will open a save file dialogue to select the save location for the settings file. Will set the settings and then close the window.'};
            app.SaveSettingsButton.Text = 'Save Settings';
            app.Panel = uipanel(app.ImportSettingsUIFigure);
            app.Panel.Title = 'Import Data Units';
            app.PixelsizeinUnitEditFieldLabel = uilabel(app.Panel);
            app.PixelsizeinUnitEditFieldLabel.Text = 'Pixel size in Unit';
            app.PixelsizeinUnitEditField = uieditfield(app.Panel, 'numeric');
            app.PixelsizeinUnitEditField.Limits = [0.0001 Inf];
            app.PixelsizeinUnitEditField.Tooltip = {'Your Pixelsize in Nanometer'};
            app.PixelsizeinUnitEditField.Value = 160;
            app.TimestepinUnitEditFieldLabel = uilabel(app.Panel);
            app.TimestepinUnitEditFieldLabel.Text = 'Timestep in Unit';
            app.TimestepinUnitEditField = uieditfield(app.Panel, 'numeric');
            app.TimestepinUnitEditField.Limits = [1e-05 Inf];
            app.TimestepinUnitEditField.Tooltip = {'Time between consectuive frames in miliseconds.'};
            app.TimestepinUnitEditField.Value = 40;
            app.MetricLengthUnitEditFieldLabel = uilabel(app.Panel);
            app.MetricLengthUnitEditFieldLabel.Text = 'Metric Length Unit';
            app.MetricLengthUnitEditField = uieditfield(app.Panel, 'text');
            app.MetricLengthUnitEditField.ValueChangedFcn = createCallbackFcn(app, @MetricLengthUnitEditFieldValueChanged, true);
            app.MetricLengthUnitEditField.Tooltip = {'Enter the metic length unit abbreviation of your xyz data. Usually mm, µm or nm.'};
            app.MetricLengthUnitEditField.Value = 'nm';
            app.TimeUnitEditFieldLabel = uilabel(app.Panel);
            app.TimeUnitEditFieldLabel.Text = 'Time Unit';
            app.TimeUnitEditField = uieditfield(app.Panel, 'text');
            app.TimeUnitEditField.ValueChangedFcn = createCallbackFcn(app, @TimeUnitEditFieldValueChanged, true);
            app.TimeUnitEditField.Value = 'ms';
            app.UnitDataConstellationButtonGroup = uibuttongroup(app.Panel);
            app.UnitDataConstellationButtonGroup.Title = 'Unit Data Constellation';
            app.PixelFrameButton = uiradiobutton(app.UnitDataConstellationButtonGroup);
            app.PixelFrameButton.Text = 'Pixel/Frame';
            app.PixelUnitButton = uiradiobutton(app.UnitDataConstellationButtonGroup);
            app.PixelUnitButton.Text = 'Pixel/Unit';
            app.UnitFrameButton = uiradiobutton(app.UnitDataConstellationButtonGroup);
            app.UnitFrameButton.Text = 'Unit/Frame';
            app.UnitFrameButton.Value = true;
            app.UnitUnitButton = uiradiobutton(app.UnitDataConstellationButtonGroup);
            app.UnitUnitButton.Text = 'Unit/Unit';
            app.LoadSettingsButton = uibutton(app.ImportSettingsUIFigure, 'push');
            app.LoadSettingsButton.ButtonPushedFcn = createCallbackFcn(app, @LoadSettingsButtonPushed, true);
            app.LoadSettingsButton.Tooltip = {'Will open a file selecte dialogue to allow for loading of settings that have ben previously saved. You will need to "Set" the settings for them to be used by the software.'};
            app.LoadSettingsButton.Text = 'Load Settings';
            app.SetSettingsButton = uibutton(app.ImportSettingsUIFigure, 'push');
            app.SetSettingsButton.ButtonPushedFcn = createCallbackFcn(app, @SetSettingsButtonPushed, true);
            app.SetSettingsButton.Tooltip = {'Will set the settings for the main app. This will close the current window.'};
            app.SetSettingsButton.Text = 'Set Settings';
            % Show the figure after all components are created
            app.ImportSettingsUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = ImportSettingsValidationWindow(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.ImportSettingsUIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.ImportSettingsUIFigure)
        end
    end
end